classdef showVol_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure                   matlab.ui.Figure
        CameraToolBar            matlab.ui.container.Toolbar
        orbit                    matlab.ui.container.toolbar.ToggleTool
        orbitscenelight          matlab.ui.container.toolbar.ToggleTool
        pan                      matlab.ui.container.toolbar.ToggleTool
        dollyhv                  matlab.ui.container.toolbar.ToggleTool
        dollyfb                  matlab.ui.container.toolbar.ToggleTool
        zoom                     matlab.ui.container.toolbar.ToggleTool
        roll                     matlab.ui.container.toolbar.ToggleTool
        x                        matlab.ui.container.toolbar.ToggleTool
        y                        matlab.ui.container.toolbar.ToggleTool
        z                        matlab.ui.container.toolbar.ToggleTool
        none                     matlab.ui.container.toolbar.ToggleTool
        togglescenelight         matlab.ui.container.toolbar.PushTool
        orthographic             matlab.ui.container.toolbar.PushTool
        perspective              matlab.ui.container.toolbar.PushTool
        reset                    matlab.ui.container.toolbar.PushTool
        stopmoving               matlab.ui.container.toolbar.PushTool
        cycleOrientations2       matlab.ui.control.Button
        cycleOrientations1       matlab.ui.control.Button
        toggleLines3_1           matlab.ui.control.StateButton
        toggleLines2_1           matlab.ui.control.StateButton
        toggleLines1_1           matlab.ui.control.StateButton
        text_scrval              matlab.ui.control.Label
        text_scrvalstr           matlab.ui.control.Label
        gotoPoint                matlab.ui.control.StateButton
        swapPoint                matlab.ui.control.StateButton
        storePoint               matlab.ui.control.StateButton
        zoom_reset               matlab.ui.control.Button
        darken_reset             matlab.ui.control.Button
        zoom_slider              matlab.ui.control.Slider
        darken_slider            matlab.ui.control.Slider
        contrast_reset           matlab.ui.control.Button
        contrast_slider          matlab.ui.control.Slider
        edit_command             matlab.ui.control.EditField
        text_last_echo           matlab.ui.control.Label
        text_command             matlab.ui.control.Label
        text_last                matlab.ui.control.Label
        cycleVolumes_1           matlab.ui.control.Button
        incrementSliceNumber_1   matlab.ui.control.Button
        incrementColumnNumber_1  matlab.ui.control.Button
        decrementSliceNumber_1   matlab.ui.control.Button
        decrementColumnNumber_1  matlab.ui.control.Button
        incrementRowNumber_1     matlab.ui.control.Button
        decrementRowNumber_1     matlab.ui.control.Button
        text_voxval              matlab.ui.control.Label
        text_voxvalstr           matlab.ui.control.Label
        text_hh                  matlab.ui.control.Label
        text_pp                  matlab.ui.control.Label
        text_ll                  matlab.ui.control.Label
        text_H                   matlab.ui.control.Label
        edit_ss                  matlab.ui.control.EditField
        edit_cc                  matlab.ui.control.EditField
        edit_rr                  matlab.ui.control.EditField
        text_slice               matlab.ui.control.Label
        text_col                 matlab.ui.control.Label
        text_row                 matlab.ui.control.Label
        text_L                   matlab.ui.control.Label
        text_P                   matlab.ui.control.Label
        axes3                    matlab.ui.control.UIAxes
        axes2                    matlab.ui.control.UIAxes
        axes1                    matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function axesCR_ButtonDownFcn(app, hObject, eventdata, handles)
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            pos = get(hObject, 'CurrentPoint');
            ima = get(handles.imCR,'CData');
            handles.cc = round(interp1([0 size(ima,2)+1],[0 handles.ccMax+1],pos(1,1),'linear','extrap'));
            handles.cc = max(handles.cc, 1);
            handles.cc = min(handles.cc, handles.ccMax);
            handles.rr = round(interp1([0 size(ima,1)+1],[0 handles.rrMax+1],pos(1,2),'linear','extrap'));
            handles.rr = max(handles.rr, 1);
            handles.rr = min(handles.rr, handles.rrMax);
            handles = updateDisplay_cc(app, handles);
            handles = updateDisplay_rr(app, handles);
            %handles = updateDisplay_newvol(handles);
            printInfo(app, handles);
            guidata(hObject, handles);
        end
        
        function axesCS_ButtonDownFcn(app, hObject, eventdata, handles)
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            pos = get(hObject, 'CurrentPoint');
            ima = get(handles.imCS,'CData');
            handles.cc = round(interp1([0 size(ima,2)+1],[0 handles.ccMax+1],pos(1,1),'linear','extrap'));
            handles.cc = max(handles.cc, 1);
            handles.cc = min(handles.cc, handles.ccMax);
            handles.ss = round(interp1([0 size(ima,1)+1],[0 handles.ssMax+1],pos(1,2),'linear','extrap'));
            handles.ss = max(handles.ss, 1);
            handles.ss = min(handles.ss, handles.ssMax);
            handles = updateDisplay_cc(app, handles);
            handles = updateDisplay_ss(app, handles);
            %handles = updateDisplay_newvol;
            printInfo(app, handles);
            guidata(hObject, handles);
        end
        
        function axesSR_ButtonDownFcn(app, hObject, eventdata, handles)
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            pos = get(hObject, 'CurrentPoint');
            ima = get(handles.imSR,'CData');
            handles.ss = round(interp1([0 size(ima,2)+1],[0 handles.ssMax+1],pos(1,1),'linear','extrap'));
            handles.ss = max(handles.ss, 1);
            handles.ss = min(handles.ss, handles.ssMax);
            handles.rr = round(interp1([0 size(ima,1)+1],[0 handles.rrMax+1],pos(1,2),'linear','extrap'));
            handles.rr = max(handles.rr, 1);
            handles.rr = min(handles.rr, handles.rrMax);
            handles = updateDisplay_ss(app, handles);
            handles = updateDisplay_rr(app, handles);
            %handles = updateDisplay_newvol;
            printInfo(app, handles);
            guidata(hObject, handles);
        end
        
        function handles = cycleOrientationsForward(app, handles)
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            handles.ORIENTATION = handles.ORIENTATION + 1;
            if handles.ORIENTATION > 3
              handles.ORIENTATION = 1;
            end
            handles = displayNewOrientation(app, handles);
        end
        
        function handles = cycleOrientationsReverse(app, handles)
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            handles.ORIENTATION = handles.ORIENTATION - 1;
            if handles.ORIENTATION < 1
              handles.ORIENTATION = 3;
            end
            handles = displayNewOrientation(app, handles);
        end
        
        function handles = cycleVolumes(app, handles)
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            tic
            
            set(handles.currentVolPB, 'ForegroundColor', 'k');
            tmp = find(handles.hideVol == 0);
            tmpInd = find(tmp > handles.currentVol);
            if isempty(tmpInd)
              tmpInd = 1;
            end
            handles.currentVol = tmp(tmpInd(1));
            handles.currentVolPB = handles.volPB(handles.currentVol);
            set(handles.currentVolPB, 'ForegroundColor', 'r');
            handles.Mvxl2lph = handles.vols{handles.currentVol}.Mvxl2lph;
            
            cVal = handles.cVal(handles.currentVol);
            set(handles.contrast_slider, 'Value', cVal);
            dVal = handles.dVal(handles.currentVol);
            set(handles.darken_slider, 'Value', dVal);
            handles = updateDisplay_clims(app, handles);
            
            updateDisplay_newvol(app, handles);
            
            if isfield(handles,'debug') && handles.debug
              toc
            end
        end
        
        function handles = cycleVolumesMovie(app, handles, numLoops, pauseTime)
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            numVisible = length(find(handles.hideVol == 0));
            if numVisible > 1
              for counter = 1 : numLoops * numVisible
                handles = cycleVolumes(app, handles);
                if pauseTime > -eps
                  pause(pauseTime);
                end
              end
            end
        end
        
        function handles = decrementColumnNumber(app, handles)
            
            if isfield(handles,'debug') && handles.debug
              fprintf(1,'decrementColumnNumber\n');
              PrintStack(dbstack)
            end
            
            if handles.cc > 1
              handles.cc = handles.cc - 1;
            end
            handles = updateDisplay_newvol(app, handles);
            %handles = updateDisplay_cc(handles);
            printInfo(app, handles);
        end
        
        function handles = decrementRowNumber(app, handles)
            
            if isfield(handles,'debug') && handles.debug
              fprintf(1,'decrementRowNumber\n');
              PrintStack(dbstack)
            end
            
            if handles.rr > 1
              handles.rr = handles.rr - 1;
            end
            handles = updateDisplay_newvol(app, handles);
            %handles = updateDisplay_rr(handles);
            printInfo(app, handles);
        end
        
        function handles = decrementSliceNumber(app, handles)
            
            if isfield(handles,'debug') && handles.debug
              fprintf(1,'decrementSliceNumber\n');
              PrintStack(dbstack)
            end
            
            if handles.ss > 1
              handles.ss = handles.ss - 1;
            end
            handles = updateDisplay_newvol(app, handles);
            %handles = updateDisplay_ss(handles);
            printInfo(app, handles);
        end
        
        function handles = displayNewOrientation(app, handles)
            %% ------------------------------------------------------------
            %% --- Change the display upon setting a new orientation
            %% ------------------------------------------------------------
            
            
            if isfield(handles,'debug') && handles.debug
              fprintf(1,'displayNewOrientation\n');
              PrintStack(dbstack)
            end
            
            
            switch handles.ORIENTATION
             case 1
              handles.axesCR = handles.axes1;
              handles.axesSR = handles.axes2;
              handles.axesCS = handles.axes3;
              if ~isfield(handles,'initialized')
               handles = newDisplay(app, handles);
              end
              handles.axes1_X = [handles.ClineCR handles.RlineCR ...
            		     handles.CtextCR handles.RtextCR];
              handles.axes2_X = [handles.SlineSR handles.RlineSR ...
            		     handles.StextSR handles.RtextSR];
              handles.axes3_X = [handles.ClineCS handles.SlineCS ...
            		     handles.CtextCS handles.StextCS];
              handles.ccDecrChar = 'j';
              handles.ccIncrChar = 'l';
              handles.rrDecrChar = 'i';
              handles.rrIncrChar = 'k';
              handles.ssDecrChar = ',';
              handles.ssIncrChar = '.';
            
             case 2
              handles.axesSR = handles.axes1;
              handles.axesCS = handles.axes2;
              handles.axesCR = handles.axes3;
              if ~isfield(handles,'initialized')
               handles = newDisplay(app, handles);
              end
              handles.axes1_X = [handles.SlineSR handles.RlineSR ...
            		     handles.StextSR handles.RtextSR];
              handles.axes2_X = [handles.ClineCS handles.SlineCS ...
            		     handles.CtextCS handles.StextCS];
              handles.axes3_X = [handles.ClineCR handles.RlineCR ...
            		     handles.CtextCR handles.RtextCR];
              handles.ssDecrChar = 'j';
              handles.ssIncrChar = 'l';
              handles.rrDecrChar = 'i';
              handles.rrIncrChar = 'k';
              handles.ccDecrChar = ',';
              handles.ccIncrChar = '.';
            
             case 3
              handles.axesCS = handles.axes1;
              handles.axesCR = handles.axes2;
              handles.axesSR = handles.axes3;
              if ~isfield(handles,'initialized')
               handles = newDisplay(app, handles);
              end
              handles.axes1_X = [handles.ClineCS handles.SlineCS ...
            		     handles.CtextCS handles.StextCS];
              handles.axes2_X = [handles.ClineCR handles.RlineCR ...
            		     handles.CtextCR handles.RtextCR];
              handles.axes3_X = [handles.SlineSR handles.RlineSR ...
            		     handles.StextSR handles.RtextSR];
              handles.ccDecrChar = 'j';
              handles.ccIncrChar = 'l';
              handles.ssDecrChar = 'k';
              handles.ssIncrChar = 'i';
              handles.rrDecrChar = ',';
              handles.rrIncrChar = '.';
            
            end
            
            if handles.HIDE1
              set(handles.axes1_X, 'Visible', 'off');
            end
            if handles.HIDE2
              set(handles.axes2_X, 'Visible', 'off');
            end
            if handles.HIDE3
              set(handles.axes3_X, 'Visible', 'off');
            end
            
            handles = updateDisplay_newvol(app, handles);
        end
        
        function handles = fly(app, handles, stopIndex, varargin)
            
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            if nargin < 3
              pauseTime = 0;
            else
              pauseTime = varargin{1};
            end
            
            switch handles.ORIENTATION
             case 1
              handles = fly_ss(app, handles, stopIndex, pauseTime);
             case 2
              handles = fly_cc(app, handles, stopIndex, pauseTime);
             case 3
              handles = fly_rr(app, handles, stopIndex, pauseTime);
            end
        end
        
        function handles = fly_cc(app, handles, stop_cc, pauseTime)
            
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            % Start
            start_cc = handles.cc;
            
            % Stop
            stop_cc = max(stop_cc, 1);
            stop_cc = min(stop_cc, handles.ccMax);
            
            % Step
            if stop_cc > handles.cc
              step = +1;
            else
              step = -1;
            end
            
            % Begin loop
            set(handles.toggleLines1, 'Value', 1);
            set(handles.toggleLines1, 'ForegroundColor', 'k');
            set(handles.axes1_X, 'Visible', 'off');
            for cc = start_cc : step : stop_cc
              handles.cc = cc;
              handles = updateDisplay_cc(app, handles);
              printInfo(app, handles); % !@#
              pause(pauseTime);
            end
            if handles.HIDE1 == 0
              set(handles.toggleLines1, 'Value', 0);
              set(handles.toggleLines1, 'ForegroundColor', 'r');
              set(handles.axes1_X, 'Visible', 'on');
            end
        end
        
        function handles = fly_rr(app, handles, stop_rr, pauseTime)
            
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            % Start
            start_rr = handles.rr;
            
            % Stop
            stop_rr = max(stop_rr, 1);
            stop_rr = min(stop_rr, handles.rrMax);
            
            % Step
            if stop_rr > handles.rr
              step = +1;
            else
              step = -1;
            end
            
            % Begin loop
            set(handles.toggleLines1, 'Value', 1);
            set(handles.toggleLines1, 'ForegroundColor', 'k');
            set(handles.axes1_X, 'Visible', 'off');
            for rr = start_rr : step : stop_rr
              handles.rr = rr;
              handles = updateDisplay_rr(app, handles);
              printInfo(app, handles); % !@#
              pause(pauseTime);
            end
            if handles.HIDE1 == 0
              set(handles.toggleLines1, 'Value', 0);
              set(handles.toggleLines1, 'ForegroundColor', 'r');
              set(handles.axes1_X, 'Visible', 'on');
            end
        end
        
        function handles = fly_ss(app, handles, stop_ss, pauseTime)
            
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            % Start
            start_ss = handles.ss;
            
            % Stop
            stop_ss = max(stop_ss, 1);
            stop_ss = min(stop_ss, handles.ssMax);
            
            % Step
            if stop_ss > handles.ss
              step = +1;
            else
              step = -1;
            end
            
            % Begin loop
            set(handles.toggleLines1, 'Value', 1);
            set(handles.toggleLines1, 'ForegroundColor', 'k');
            set(handles.axes1_X, 'Visible', 'off');
            for ss = start_ss : step : stop_ss
              handles.ss = ss;
              handles = updateDisplay_ss(app, handles);
              printInfo(app, handles); % !@#
              pause(pauseTime);
            end
            if handles.HIDE1 == 0
              set(handles.toggleLines1, 'Value', 0);
              set(handles.toggleLines1, 'ForegroundColor', 'r');
              set(handles.axes1_X, 'Visible', 'on');
            end
            printInfo(app, handles);
        end
        
        function handles = gotoPointLPH(app, handles, targetLPH)
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            Mlph2vxl = inv(handles.Mvxl2lph);
            targetRCS = Mlph2vxl * targetLPH;
            rr = round(targetRCS(1));
            cc = round(targetRCS(2));
            ss = round(targetRCS(3));
            if ((rr >= 1) & (rr <= handles.rrMax) & ...
                (cc >= 1) & (cc <= handles.ccMax) & ...
                (ss >= 1) & (ss <= handles.ssMax))
              handles.rr = rr;
              handles.cc = cc;
              handles.ss = ss;
              handles = updateDisplay_newvol(app, handles);
            %  handles = updateDisplay_rr(handles);
            %  handles = updateDisplay_cc(handles);
            %  handles = updateDisplay_ss(handles);
              printInfo(app, handles);
            else
              set(handles.edit_command, 'String', ...
            		    'Outside the volume!');
            end
        end
        
        function handles = gotoPointRCS(app, handles, targetRCS)
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            rr = round(targetRCS(1));
            cc = round(targetRCS(2));
            ss = round(targetRCS(3));
            if ((rr >= 1) & (rr <= handles.rrMax) & ...
                (cc >= 1) & (cc <= handles.ccMax) & ...
                (ss >= 1) & (ss <= handles.ssMax))
              handles.rr = rr;
              handles.cc = cc;
              handles.ss = ss;
              handles = updateDisplay_newvol(app, handles);
            %  handles = updateDisplay_rr(handles);
            %  handles = updateDisplay_cc(handles);
            %  handles = updateDisplay_ss(handles);
              printInfo(app, handles);
            else
              set(handles.edit_command, 'String', ...
            		    'Outside the volume!');
            end
        end
        
        function handles = gotoStoredPoint(app, handles)
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            set(handles.gotoPoint, 'Value', 0);
            handles.rr = handles.rrStored;
            handles.cc = handles.ccStored;
            handles.ss = handles.ssStored;
            handles = updateDisplay_newvol(app, handles);
            %handles = updateDisplay_rr(handles);
            %handles = updateDisplay_cc(handles);
            %handles = updateDisplay_ss(handles);
            printInfo(app, handles);
        end
        
        function handles = incrementColumnNumber(app, handles)
            
            if isfield(handles,'debug') && handles.debug
              fprintf(1,'incrementColumnNumber\n');
              PrintStack(dbstack)
            end
            
            if handles.cc < handles.ccMax
              handles.cc = handles.cc + 1;
            end
            handles = updateDisplay_newvol(app, handles);
            %handles = updateDisplay_cc(handles);
            printInfo(app, handles);
        end
        
        function handles = incrementRowNumber(app, handles)
            
            if isfield(handles,'debug') && handles.debug
              fprintf(1,'incrementRowNumber\n');
              PrintStack(dbstack)
            end
            
            if handles.rr < handles.rrMax
              handles.rr = handles.rr + 1;
            end
            handles = updateDisplay_newvol(app, handles);
            %handles = updateDisplay_rr(handles);
            printInfo(app, handles);
        end
        
        function handles = incrementSliceNumber(app, handles)
            
            if isfield(handles,'debug') && handles.debug
              fprintf(1,'incrementSliceNumber\n');
              PrintStack(dbstack)
            end
            
            if handles.ss < handles.ssMax
              handles.ss = handles.ss + 1;
            end
            handles = updateDisplay_newvol(app, handles);
            %handles = updateDisplay_ss(handles);
            printInfo(app, handles);
        end
        
        function handles = meshCrossSection_cc(app, handles)
            %% ------------------------------------------------------------
            %% --- Compute meshCrossSection for col = constant
            %% ------------------------------------------------------------
            
            
            if isfield(handles,'debug') && handles.debug
              fprintf(1,'meshCrossSection_cc\n');
              PrintStack(dbstack)
            end
            
            for mmm = 1:handles.numSurfs
            
              %% Draw in axis SR, which draws imgs(:,j,:)
              axes(handles.axesSR);
              [startPts, endPts] = findContour(handles.surfs(mmm), ...
            				   handles.surfs(mmm).vertices(:,2), ...
            				   handles.cc);
            
              startR = startPts(:,1); endR = endPts(:,1);
              startS = startPts(:,3); endS = endPts(:,3);
            
              hold on;
              lh = quiver(startS, startR, endS-startS, endR-startR, 0, handles.surfs(mmm).linestyle);
              hold off
            
              set(lh, 'Color', handles.surfs(mmm).color);
              set(lh, 'LineWidth', handles.surfs(mmm).linewidth);
              handles.meshCrossSectionSR(mmm).lh = lh;
            
              if handles.showSurf(mmm) == 0
                set(handles.meshCrossSectionSR(mmm).lh, 'Visible', 'off');
              end
            
            end
        end
        
        function handles = meshCrossSection_rr(app, handles)
            %% ------------------------------------------------------------
            %% --- Compute meshCrossSection for row = constant
            %% ------------------------------------------------------------


            if isfield(handles,'debug') && handles.debug
              fprintf(1,'meshCrossSection_rr\n');
              PrintStack(dbstack)
            end
            
            for mmm = 1:handles.numSurfs
            
              %% Draw in axis CS, which draws imgs(i,:,:)
              axes(handles.axesCS);
              [startPts, endPts] = findContour(handles.surfs(mmm), ...
            				   handles.surfs(mmm).vertices(:,1), ...
            				   handles.rr);
            
              startC = startPts(:,2); endC = endPts(:,2);
              startS = startPts(:,3); endS = endPts(:,3);
            
              hold on;
              lh = quiver(startC, startS, endC-startC, endS-startS, 0, handles.surfs(mmm).linestyle);
              hold off
            
              set(lh, 'Color', handles.surfs(mmm).color);
              set(lh, 'LineWidth', handles.surfs(mmm).linewidth);
              handles.meshCrossSectionCS(mmm).lh = lh;
            
              if handles.showSurf(mmm) == 0
                set(handles.meshCrossSectionCS(mmm).lh, 'Visible', 'off');
              end
            
            end
        end
        
        function handles = meshCrossSection_ss(app, handles)
            %% ------------------------------------------------------------
            %% --- Compute meshCrossSection for slice = constant
            %% ------------------------------------------------------------
            
            
            if isfield(handles,'debug') && handles.debug
              fprintf(1,'meshCrossSection_ss\n');
              PrintStack(dbstack)
            end
            
            for mmm = 1:handles.numSurfs
            
              %% Draw in axesCR, which draws imgs(:,:,k)
              axes(handles.axesCR);
              [startPts, endPts] = findContour(handles.surfs(mmm),handles.surfs(mmm).vertices(:,3),handles.ss);
            
              startR = startPts(:,1); endR = endPts(:,1);
              startC = startPts(:,2); endC = endPts(:,2);
            
              hold on;
              lh = quiver(startC, startR, endC-startC, endR-startR, 0, handles.surfs(mmm).linestyle); % Why use quiver?
              hold off
            
              set(lh, 'Color', handles.surfs(mmm).color);
              set(lh, 'LineWidth', handles.surfs(mmm).linewidth);
              handles.meshCrossSectionCR(mmm).lh = lh;
            
              if handles.showSurf(mmm) == 0
                set(handles.meshCrossSectionCR(mmm).lh, 'Visible', 'off');
              end
            
            end
        end
        
        function meshPB_Callback(app, hObject, eventdata, handles)
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            [minVal, chosenSurf] = min(abs(handles.meshPB - hObject));
            handles.showSurf(chosenSurf) = 1 - handles.showSurf(chosenSurf);
            if handles.showSurf(chosenSurf)
              set(handles.meshCrossSectionCR(chosenSurf).lh, 'Visible', 'on');
              set(handles.meshCrossSectionSR(chosenSurf).lh, 'Visible', 'on');
              set(handles.meshCrossSectionCS(chosenSurf).lh, 'Visible', 'on');
              set(hObject, 'ForegroundColor', handles.surfs(chosenSurf).color);
              set(hObject, 'TooltipString', 'Hide this mesh');
            else
              set(handles.meshCrossSectionCR(chosenSurf).lh, 'Visible', 'off');
              set(handles.meshCrossSectionSR(chosenSurf).lh, 'Visible', 'off');
              set(handles.meshCrossSectionCS(chosenSurf).lh, 'Visible', 'off');
              set(hObject, 'ForegroundColor', [0.5 0.5 0.5]);
              set(hObject, 'TooltipString', 'Show this mesh');
            end
            guidata(hObject, handles);
        end
        
        function handles = newDisplay(app, handles)
            
            if isfield(handles,'debug') && handles.debug
              fprintf(1,'newDisplay\n');
              PrintStack(dbstack)
            end
            
            handles.initialized = true;
            
            %% Draw in axesCR, which draws imgs(:,:,k)
            axes(handles.axesCR);
            colormap(gray);
            if isfield(handles.vols{handles.currentVol},'imgs')
              if size(handles.vols{handles.currentVol}.imgs,4)==3
                ima = squeeze(handles.vols{handles.currentVol}.imgs(:,:,handles.ss,:));
                handles.imCR = image(ima);
              elseif size(handles.vols{handles.currentVol}.imgs,4)==1
                ima = squeeze(handles.vols{handles.currentVol}.imgs(:,:,handles.ss));
                handles.imCR = imagesc(ima);
              end
              if isfield(handles.vols{handles.currentVol},'cmap')
                colormap(handles.vols{handles.currentVol}.cmap);
              end
            elseif isfield(handles.vols{handles.currentVol},'imgs1')
              ima = handles.vols{handles.currentVol}.imgs3{handles.ss};
              handles.imCR = image(ima);
            end
            axis image
            set(handles.axesCR, 'DataAspectRatio', [handles.voxSize(1) handles.voxSize(2) 1]);
            handles.CtextCR = text(handles.cc, 1, 'S');
            handles.RtextCR = text(1, handles.rr, 'R');
            handles.ClineCR = line(size(ima,2)/handles.ccMax*[handles.cc handles.cc],size(ima,1)/handles.rrMax*[0.5 0.5+handles.rrMax]);
            handles.RlineCR = line(size(ima,2)/handles.ccMax*[0.5 0.5+handles.ccMax],size(ima,1)/handles.rrMax*[handles.rr handles.rr]);
            
            %% Draw in axis SR, which draws imgs(:,j,:)
            axes(handles.axesSR);
            colormap(gray);
            if isfield(handles.vols{handles.currentVol},'imgs')
              if size(handles.vols{handles.currentVol}.imgs,4)==3
                ima = squeeze(handles.vols{handles.currentVol}.imgs(:,handles.cc,:,:));
                handles.imSR = image(ima);
              else
                ima = squeeze(handles.vols{handles.currentVol}.imgs(:,handles.cc,:));
                handles.imSR = imagesc(ima);
              end
              if isfield(handles.vols{handles.currentVol},'cmap')
                colormap(handles.vols{handles.currentVol}.cmap);
              end
            elseif isfield(handles.vols{handles.currentVol},'imgs1')
              ima = handles.vols{handles.currentVol}.imgs2{handles.cc};
              handles.imSR = image(ima);
            end
            axis image
            set(handles.axesSR, 'DataAspectRatio', [handles.voxSize(1) handles.voxSize(3) 1]);
            handles.StextSR = text(handles.ss, 1, 'S');
            handles.RtextSR = text(1, handles.rr, 'P');
            handles.SlineSR = line(size(ima,2)/handles.ssMax*[handles.ss handles.ss],size(ima,1)/handles.rrMax*[0.5 0.5+handles.rrMax]);
            handles.RlineSR = line(size(ima,2)/handles.ssMax*[0.5 0.5+handles.ssMax],size(ima,1)/handles.rrMax*[handles.rr handles.rr]);
            
            %% Draw in axis CS, which draws imgs(i,:,:)
            axes(handles.axesCS);
            colormap(gray);
            if isfield(handles.vols{handles.currentVol},'imgs')
              if size(handles.vols{handles.currentVol}.imgs,4)==3
                ima = permute(squeeze(handles.vols{handles.currentVol}.imgs(handles.rr,:,:,:)),[2 1 3]);
                handles.imCS = image(ima);
              else
                ima = squeeze(handles.vols{handles.currentVol}.imgs(handles.rr,:,:))';
                handles.imCS = imagesc(ima);
              end
              if isfield(handles.vols{handles.currentVol},'cmap')
                colormap(handles.vols{handles.currentVol}.cmap);
              end
            elseif isfield(handles.vols{handles.currentVol},'imgs1')
              ima = handles.vols{handles.currentVol}.imgs1{handles.rr};
              handles.imCS = image(ima);
            end
            axis image; axis xy
            set(handles.axesCS, 'DataAspectRatio', [handles.voxSize(3) handles.voxSize(2) 1]);
            handles.CtextCS = text(handles.cc, handles.ssMax, 'A');
            handles.StextCS = text(1, handles.ss, 'R');
            handles.ClineCS = line(size(ima,2)/handles.ccMax*[handles.cc handles.cc],size(ima,1)/handles.ssMax*[0.5 0.5+handles.ssMax]);
            handles.SlineCS = line(size(ima,2)/handles.ccMax*[0.5 0.5+handles.ccMax],size(ima,1)/handles.ssMax*[handles.ss handles.ss]);
            
            %% For all axes
            axesHandles = [handles.axesCR handles.axesSR handles.axesCS];
            set(axesHandles, 'Color', 'k');
            set(axesHandles, 'CLim', handles.CLim(handles.currentVol, :));
            
            textHandles = [handles.CtextCR handles.RtextCR ...
            	       handles.StextSR handles.RtextSR ...
            	       handles.CtextCS handles.StextCS];
            set(textHandles, 'VerticalAlignment', 'top');
            set(textHandles, 'HorizontalAlignment', 'left');
            set(textHandles, 'Color', 'r');
            
            lineHandles = [handles.ClineCR handles.RlineCR ...
            	       handles.SlineSR handles.RlineSR ...
            	       handles.ClineCS handles.SlineCS];
            set(lineHandles, 'Color', [0.5 0.5 0], 'LineStyle', ':','LineWidth',handles.LineWidth);
            
            %fprintf(1,'newDisplay\n');
            
            %% Draw mesh cross sections
            handles = meshCrossSection_ss(app, handles);
            handles = meshCrossSection_cc(app, handles);
            handles = meshCrossSection_rr(app, handles);
            
            updateDisplay_zoom(app, handles);
        end
        
        function handles = parseCommand(app, handles, str)
            
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            %% Empty -- do nothing
            str = deblank(str);
            if isempty(str)
              return
            end
            
            %% Jump to slice/col/row?
            num = str2double(str);
            if isfinite(num)
              switch handles.ORIENTATION
               case 1,
                ss = round(num);
                if (ss >= 1) & (ss <= handles.ssMax)
                  handles.ss = ss;
                  handles = updateDisplay_ss(app, handles);
                  printInfo(app, handles);
                end
               case 2,
                cc = round(num);
                if (cc >= 1) & (cc <= handles.ccMax)
                  handles.cc = cc;
                  handles = updateDisplay_cc(app, handles);
                  printInfo(app, handles);
                end
               case 3,
                rr = round(num);
                if (rr >= 1) & (rr <= handles.rrMax)
                  handles.rr = rr;
                  handles = updateDisplay_rr(app, handles);
                  printInfo(app, handles);
                end
              end
              return;
            end
            
            %% Possible command in str
            [strFirst, strRest] = strtok(str);
            switch strFirst
            
              %% ------------------------------------------------------------
             case 'o'
              %% ------------------------------------------------------------
            
              handles = cycleOrientationsForward(app, handles);
            
              %% ------------------------------------------------------------
             case {'f', 'fly'}
              %% ------------------------------------------------------------
            
              WRONG_FORMAT = 1;
              if ~isempty(strRest)
                [strStopIndex, strRest] = strtok(strRest);
                stopIndex = round(str2double(strStopIndex));
                if isfinite(stopIndex)
                  if isempty(strRest)
            	handles = fly(app, handles, stopIndex);
            	WRONG_FORMAT = 0;
                  else
            	[strPauseTime, strRest] = strtok(strRest);
            	pauseTime = str2double(strPauseTime);
            	if isfinite(pauseTime)
            	  if pauseTime >= 0
            	    handles = fly(app, handles, stopIndex, pauseTime);
            	    WRONG_FORMAT = 0;
            	  end
            	end
                  end
                end
              end
              if WRONG_FORMAT
                set(handles.edit_command, 'String', ...
            		      'fly stopIndex [pauseTime]');
              end
            
              %% ------------------------------------------------------------
             case {'c', 'cycle'}
              %% ------------------------------------------------------------
            
              WRONG_FORMAT = 1;
              numLoops = 10;
              pauseTime = 0.1;
              if isempty(strRest)
                WRONG_FORMAT = 0;
                handles = cycleVolumesMovie(app, handles, numLoops, pauseTime);
              else
                [strNumLoops, strRest] = strtok(strRest);
                numLoops = round(str2double(strNumLoops));
                if numLoops > 0
                  if isempty(strRest)
            	WRONG_FORMAT = 0;
            	handles = cycleVolumesMovie(app, handles, numLoops, pauseTime);
                  else
            	[strPauseTime, strRest] = strtok(strRest);
            	pauseTime = str2double(strPauseTime);
            	if pauseTime >= 0
            	  WRONG_FORMAT = 0;
            	  handles = cycleVolumesMovie(app, handles, numLoops, pauseTime);
            	end
                  end
                end
              end
              if WRONG_FORMAT
                set(handles.edit_command, 'String', ...
            		      'cycle [numLoops] [pauseTime]');
              end
            
              %% ------------------------------------------------------------
             case 'lph'
              %% ------------------------------------------------------------
            
              WRONG_FORMAT = 1;
              if ~isempty(strRest)
                [strL, strRest] = strtok(strRest);
                targetL = str2double(strL);
                if isfinite(targetL)
                  if ~isempty(strRest)
            	[strP, strRest] = strtok(strRest);
            	targetP = str2double(strP);
            	if isfinite(targetP)
            	  [strH, strRest] = strtok(strRest);
            	  targetH = str2double(strH);
            	  if isfinite(targetH)
            	    targetLPH = [targetL; targetP; targetH; 1];
            	    handles = gotoPointLPH(app, handles, targetLPH);
            	    WRONG_FORMAT = 0;
            	  end
            	end
                  end
                end
              end
              if WRONG_FORMAT
                set(handles.edit_command, 'String', ...
            		      'lph gotoL gotoP gotoH');
              end
            
              %% ------------------------------------------------------------
             case 'rcs'
              %% ------------------------------------------------------------
            
              WRONG_FORMAT = 1;
              if ~isempty(strRest)
                [strR, strRest] = strtok(strRest);
                targetR = str2double(strR);
                if isfinite(targetR)
                  if ~isempty(strRest)
            	[strC, strRest] = strtok(strRest);
            	targetC = str2double(strC);
            	if isfinite(targetC)
            	  [strS, strRest] = strtok(strRest);
            	  targetS = str2double(strS);
            	  if isfinite(targetS)
            	    targetRCS = [targetR; targetC; targetS; 1];
            	    handles = gotoPointRCS(app, handles, targetRCS);
            	    WRONG_FORMAT = 0;
            	  end
            	end
                  end
                end
              end
              if WRONG_FORMAT
                set(handles.edit_command, 'String', ...
            		      'rcs gotoR gotoC gotoS');
              end
            
            end
        end
        
        function handles = parse_arguments(app, handles, args)
            %% ------------------------------------------------------------
            %% --- Function to parse optional command line arguments
            %% ------------------------------------------------------------
            
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            for iii = 1:length(args)
              v = args{iii};
              if iscell(v)
                for vi = 1:length(v)
                  handles = parse_arguments(app, handles,v(vi));
                end
              elseif isstruct(v)
                if (isfield(v,'imgs')) && (isfield(v,'Mvxl2lph')) % volStruct
                  handles.numVols = handles.numVols + 1;
                  handles.vols{handles.numVols} = v;
                end
                if isfield(v,'vertices') % meshStruct
                  handles.numSurfs = handles.numSurfs + 1;
                  handles.surfs(handles.numSurfs).vertices = v.vertices;
                  handles.surfs(handles.numSurfs).faces = v.faces;
                  handles.surfs(handles.numSurfs).color = [1 1 0];
                  handles.surfs(handles.numSurfs).linewidth = 1;
                  handles.surfs(handles.numSurfs).linestyle = '-';
                  if isfield(v,'color')
                    if ~isempty(v.color)
                      handles.surfs(handles.numSurfs).color = v.color;
                    end
                  end
                  if isfield(v, 'linewidth') && ~isempty(v.linewidth)
                    handles.surfs(handles.numSurfs).linewidth = v.linewidth;
                  end
                  if isfield(v, 'linestyle') && ~isempty(v.linestyle)
                    handles.surfs(handles.numSurfs).linestyle = v.linestyle;
                  end
                end
                if isfield(v,'RCS') % Should also handle RAS, LPH
                  handles.initialRCS = v.RCS; handles.initialRCS(4) = 1;
                end
              else
                for vi = 1:size(v,4)
                  handles.numVols = handles.numVols + 1;
                  handles.vols{handles.numVols} = ctx_mgh2ctx(v(:,:,:,vi));
                end
              end
            end
        end
        
        function printInfo(app, handles)
            %% ------------------------------------------------------------
            %% --- Print info into text boxes
            %% ------------------------------------------------------------


            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            if isfield(handles.vols{handles.currentVol},'imgs')
            
              rr = handles.rr; cc = handles.cc; ss = handles.ss;
              voxVal = handles.vols{handles.currentVol}.imgs(rr,cc,ss,1);
              CLim1 = handles.CLim(handles.currentVol, 1);
              CLim2 = handles.CLim(handles.currentVol, 2);
            
            % Convert RCS to LPH
              tmp = [rr; cc; ss; 1];
              tmp = handles.Mvxl2lph * tmp;
              ll = tmp(1); pp = tmp(2); hh = tmp(3);
            
              set(handles.edit_rr, 'String', sprintf('%d', rr));
              set(handles.edit_cc, 'String', sprintf('%d', cc));
              set(handles.edit_ss, 'String', sprintf('%d', ss));
            
              set(handles.text_ll, 'String', sprintf('%.3f', ll));
              set(handles.text_pp, 'String', sprintf('%.3f', pp));
              set(handles.text_hh, 'String', sprintf('%.3f', hh));
            
              set(handles.text_voxval, 'String', sprintf('%.2f', voxVal));
            
              scrVal = (voxVal - CLim1)/(CLim2 - CLim1);
              scrVal = min(scrVal, 1);
              scrVal = max(scrVal, 0);
              set(handles.text_scrval, 'String', sprintf('%.2f', 100*scrVal));
            
            end
        end
        
        function handles = storeCurrentPoint(app, handles)
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            set(handles.storePoint, 'Value', 0);
            handles.rrStored = handles.rr;
            handles.ccStored = handles.cc;
            handles.ssStored = handles.ss;
            str = sprintf('Go to voxel (%d,%d,%d) -- keyboard shortcut: ;', ...
            	      handles.rrStored, handles.ccStored, handles.ssStored);
            set(handles.gotoPoint,'TooltipString', str);
            str = sprintf('Swap current voxel with voxel (%d,%d,%d) -- keyboard shortcut: /', ...
            	      handles.rrStored, handles.ccStored, handles.ssStored);
            set(handles.swapPoint,'TooltipString', str);
        end
        
        function handles = swapCurrentAndStoredPoints(app, handles)
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            set(handles.swapPoint, 'Value', 0);
            tmp = [handles.rr handles.cc handles.ss];
            handles.rr = handles.rrStored;
            handles.cc = handles.ccStored;
            handles.ss = handles.ssStored;
            handles.rrStored = tmp(1);
            handles.ccStored = tmp(2);
            handles.ssStored = tmp(3);
            str = sprintf('Go to voxel (%d,%d,%d) -- keyboard shortcut: ;', ...
            	      handles.rrStored, handles.ccStored, handles.ssStored);
            set(handles.gotoPoint,'TooltipString', str);
            str = sprintf('Swap current voxel with voxel (%d,%d,%d) -- keyboard shortcut: /', ...
            	      handles.rrStored, handles.ccStored, handles.ssStored);
            set(handles.swapPoint,'TooltipString', str);
            handles = updateDisplay_newvol(app, handles);
            %handles = updateDisplay_rr(handles);
            %handles = updateDisplay_cc(handles);
            %handles = updateDisplay_ss(handles);
            printInfo(app, handles);
        end
        
        function handles = toggleLines1(app, handles)
            %% ------------------------------------------------------------
            %% --- Toggle the display of crosshair in axes1
            %% ------------------------------------------------------------
            
            
            if isfield(handles,'debug') && handles.debug
              fprintf(1,'toggleLines1\n');
              PrintStack(dbstack)
            end
            
            handles.HIDE1 = 1 - handles.HIDE1;
            set(handles.toggleLines1, 'Value', handles.HIDE1);
            if handles.HIDE1
              set(handles.toggleLines1, 'ForegroundColor', 'k');
              set(handles.axes1_X, 'Visible', 'off');
            else
              set(handles.toggleLines1, 'ForegroundColor', 'r');
              set(handles.axes1_X, 'Visible', 'on');
            end
        end
        
        function handles = toggleLines2(app, handles)
            %% ------------------------------------------------------------
            %% --- Toggle the display of crosshair in axes2
            %% ------------------------------------------------------------
            
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            handles.HIDE2 = 1 - handles.HIDE2;
            set(handles.toggleLines2, 'Value', handles.HIDE2);
            if handles.HIDE2
              set(handles.toggleLines2, 'ForegroundColor', 'k');
              set(handles.axes2_X, 'Visible', 'off');
            else
              set(handles.toggleLines2, 'ForegroundColor', 'r');
              set(handles.axes2_X, 'Visible', 'on');
            end
        end
        
        function handles = toggleLines3(app, handles)
            %% ------------------------------------------------------------
            %% --- Toggle the display of crosshair in axes3
            %% ------------------------------------------------------------
            
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            handles.HIDE3 = 1 - handles.HIDE3;
            set(handles.toggleLines3, 'Value', handles.HIDE3);
            if handles.HIDE3
              set(handles.toggleLines3, 'ForegroundColor', 'k');
              set(handles.axes3_X, 'Visible', 'off');
            else
              set(handles.toggleLines3, 'ForegroundColor', 'r');
              set(handles.axes3_X, 'Visible', 'on');
            end
        end
        
        function handles = updateDisplay_cc(app, handles)
            
            
            if isfield(handles,'debug') && handles.debug
              fprintf(1,'updateDisplay_cc\n');
              PrintStack(dbstack)
            end
            
            handles = updateDisplay_newvol(app, handles);
            return
            
            if isfield(handles.vols{handles.currentVol},'imgs')
              if size(handles.vols{handles.currentVol}.imgs,4)==3
                ima = squeeze(handles.vols{handles.currentVol}.imgs(:,handles.cc,:,:));
              else
                ima = squeeze(handles.vols{handles.currentVol}.imgs(:,handles.cc,:));
              end
            elseif isfield(handles.vols{handles.currentVol},'imgs1')
              ima = handles.vols{handles.currentVol}.imgs2{handles.cc};
            end
            
            set(handles.imSR, 'CData', ima);
            set(handles.CtextCR, 'Position', [handles.cc handles.rrLim(1)]);
            set(handles.ClineCR, 'XData', [handles.cc handles.cc]);
            set(handles.CtextCS, 'Position', [handles.cc handles.ssLim(2)]);
            set(handles.ClineCS, 'XData', [handles.cc handles.cc]);
            if handles.numSurfs > 0
              delete(handles.meshCrossSectionSR.lh);
            end
            handles = meshCrossSection_cc(app, handles);
            
            updateDisplay_zoom(app, handles);
        end
        
        function handles = updateDisplay_clims(app, handles)
            %% ------------------------------------------------------------
            %% --- Compute new CLim values and update display
            %% ------------------------------------------------------------
            
            
            if isfield(handles,'debug') && handles.debug
              fprintf(1,'updateDisplay_clims\n');
              PrintStack(dbstack)
            end
            
            cVal = handles.cVal(handles.currentVol);
            dVal = handles.dVal(handles.currentVol);
            minI = handles.minI(handles.currentVol);
            maxI = handles.maxI(handles.currentVol);
            
            CLim1 = minI + (dVal-0.5/cVal)*(maxI-minI);
            CLim2 = minI + (dVal+0.5/cVal)*(maxI-minI) + eps;
            
            set(handles.axes1, 'CLim', [CLim1 CLim2]);
            set(handles.axes2, 'CLim', [CLim1 CLim2]);
            set(handles.axes3, 'CLim', [CLim1 CLim2]);
            
            handles.CLim(handles.currentVol, :) = [CLim1 CLim2];
            
            rr = handles.rr; cc = handles.cc; ss = handles.ss;
            voxVal = handles.vols{handles.currentVol}.imgs(rr,cc,ss,1);
            scrVal = (voxVal - CLim1)/(CLim2 - CLim1);
            scrVal = min(scrVal, 1);
            scrVal = max(scrVal, 0);
            set(handles.text_scrval, 'String', sprintf('%.2f', 100*scrVal));
        end
        
        function handles = updateDisplay_newvol(app, handles)
            %% ------------------------------------------------------------
            %% --- Update the display to a new volume (change in currentVol)
            %% ------------------------------------------------------------
            
            
            handles = newDisplay(app, handles); return;
            
            if isfield(handles,'debug') && handles.debug
              fprintf(1,'updateDisplay_newvol\n');
              PrintStack(dbstack)
            end
            
            %% Draw in axesCR, which draws imgs(:,:,k) % Coronal in std view
            if isfield(handles.vols{handles.currentVol},'imgs')
              if size(handles.vols{handles.currentVol}.imgs,4)==3
                ima = squeeze(handles.vols{handles.currentVol}.imgs(:,:,handles.ss,:));
              elseif size(handles.vols{handles.currentVol}.imgs,4)==1
                ima = squeeze(handles.vols{handles.currentVol}.imgs(:,:,handles.ss));
              end
            elseif isfield(handles.vols{handles.currentVol},'imgs1')
              ima = handles.vols{handles.currentVol}.imgs3{handles.ss};
            end
            set(handles.figure,'CurrentAxes',handles.axesCR)
            set(handles.imCR, 'CData', ima);
            axis image;
            set(handles.axesCR, 'DataAspectRatio', [handles.voxSize(1) handles.voxSize(2) 1]);
            handles.CtextCR.Position = [size(ima,2)/handles.ccMax*handles.cc size(ima,1)/handles.rrMax*1];
            handles.RtextCR.Position = [size(ima,2)/handles.ccMax*1 size(ima,1)/handles.rrMax*handles.rr];
            handles.ClineCR.XData = size(ima,2)/handles.ccMax*[handles.cc handles.cc]; handles.ClineCR.YData = size(ima,1)/handles.rrMax*[0.5 0.5+handles.rrMax];
            handles.RlineCR.XData = size(ima,2)/handles.ccMax*[0.5 0.5+handles.ccMax]; handles.RlineCR.YData = size(ima,1)/handles.rrMax*[handles.rr handles.rr];
            
            %% Draw in axis SR, which draws imgs(:,j,:) % Sagittal in std view
            if isfield(handles.vols{handles.currentVol},'imgs')
              if size(handles.vols{handles.currentVol}.imgs,4)==3
                ima = squeeze(handles.vols{handles.currentVol}.imgs(:,handles.cc,:,:));
              else
                ima = squeeze(handles.vols{handles.currentVol}.imgs(:,handles.cc,:));
              end
            elseif isfield(handles.vols{handles.currentVol},'imgs1')
              ima = handles.vols{handles.currentVol}.imgs2{handles.cc};
            end
            set(handles.figure,'CurrentAxes',handles.axesSR)
            set(handles.imSR, 'CData', ima);
            axis image;
            set(handles.axesSR, 'DataAspectRatio', [handles.voxSize(1) handles.voxSize(3) 1]);
            handles.StextSR.Position = [size(ima,2)/handles.ssMax*handles.ss size(ima,1)/handles.rrMax*1];
            handles.RtextSR.Position = [size(ima,2)/handles.ssMax*1 size(ima,1)/handles.rrMax*handles.rr];
            handles.SlineSR.XData = size(ima,2)/handles.ssMax*[handles.ss handles.ss]; handles.SlineSR.YData = size(ima,1)/handles.rrMax*[0.5 0.5+handles.rrMax];
            handles.RlineSR.XData = size(ima,2)/handles.ssMax*[0.5 0.5+handles.ssMax]; handles.RlineSR.YData = size(ima,1)/handles.rrMax*[handles.rr handles.rr];
            
            %% Draw in axis CS, which draws imgs(i,:,:) % Axial in std view
            if isfield(handles.vols{handles.currentVol},'imgs')
              if size(handles.vols{handles.currentVol}.imgs,4)==3
                ima = permute(squeeze(handles.vols{handles.currentVol}.imgs(handles.rr,:,:,:)),[2 1 3]);
              else
                ima = squeeze(handles.vols{handles.currentVol}.imgs(handles.rr,:,:))';
              end
            elseif isfield(handles.vols{handles.currentVol},'imgs1')
              ima = handles.vols{handles.currentVol}.imgs1{handles.rr};
            end
            set(handles.figure,'CurrentAxes',handles.axesCS)
            set(handles.imCS, 'CData', ima);
            axis image; % axis xy
            set(handles.axesCS, 'DataAspectRatio', [handles.voxSize(3) handles.voxSize(2) 1]);
            handles.CtextCS.Position = [size(ima,2)/handles.ccMax*handles.cc size(ima,1)/handles.ssMax*handles.ssMax];
            handles.StextCS.Position = [size(ima,2)/handles.ccMax*1 size(ima,1)/handles.ssMax*handles.ss];
            handles.ClineCS.XData = size(ima,2)/handles.ccMax*[handles.cc handles.cc]; handles.ClineCS.YData = size(ima,1)/handles.ssMax*[0.5 0.5+handles.ssMax];
            handles.SlineCS.XData = size(ima,2)/handles.ccMax*[0.5 0.5+handles.ccMax]; handles.SlineCS.YData = size(ima,1)/handles.ssMax*[handles.ss handles.ss];
            
            %% For all axes
            axesHandles = [handles.axesCR handles.axesSR handles.axesCS];
            set(axesHandles, 'Color', 'k');
            set(axesHandles, 'CLim', handles.CLim(handles.currentVol, :));
            
            textHandles = [handles.CtextCR handles.RtextCR ...
                           handles.StextSR handles.RtextSR ...
                           handles.CtextCS handles.StextCS];
            set(textHandles, 'VerticalAlignment', 'top');
            set(textHandles, 'HorizontalAlignment', 'left');
            set(textHandles, 'Color', 'r');
            
            lineHandles = [handles.ClineCR handles.RlineCR ...
                           handles.SlineSR handles.RlineSR ...
                           handles.ClineCS handles.SlineCS];
            set(lineHandles, 'Color', [0.5 0.5 0], 'LineStyle', ':','LineWidth',handles.LineWidth);
            
            %fprintf(1,'updateDisplay_newvol\n');
            
            %get(handles.imCS)
            
            %% Draw mesh cross sections
            handles = meshCrossSection_ss(app, handles);
            handles = meshCrossSection_cc(app, handles);
            handles = meshCrossSection_rr(app, handles);
            
            printInfo(app, handles);
            handles = updateDisplay_zoom(app, handles);
            handles = updateDisplay_clims(app, handles);
        end
        
        function handles = updateDisplay_rr(app, handles)
            
            
            if isfield(handles,'debug') && handles.debug
              fprintf(1,'updateDisplay_rr\n');
              PrintStack(dbstack)
            end
            
            handles = updateDisplay_newvol(app, handles);
            return
            
            if isfield(handles.vols{handles.currentVol},'imgs')
              if size(handles.vols{handles.currentVol}.imgs,4)==3
                ima = permute(squeeze(handles.vols{handles.currentVol}.imgs(handles.rr,:,:,:)),[2 1 3]);
              else
                ima = squeeze(handles.vols{handles.currentVol}.imgs(handles.rr,:,:))';
              end
            elseif isfield(handles.vols{handles.currentVol},'imgs1')
              ima = handles.vols{handles.currentVol}.imgs3{handles.rr};
            end
            
            set(handles.imCS, 'CData', ima);
            %handles.imCS = image(ima);
            set(handles.RtextCR, 'Position', [handles.ccLim(1) handles.rr]);
            set(handles.RlineCR, 'YData', [handles.rr handles.rr]);
            set(handles.RtextSR, 'Position', [handles.ssLim(1) handles.rr]);
            set(handles.RlineSR, 'YData', [handles.rr handles.rr]);
            if handles.numSurfs > 0
              delete(handles.meshCrossSectionCS.lh);
            end
            handles = meshCrossSection_rr(app, handles);
            
            updateDisplay_zoom(app, handles);
        end
        
        function handles = updateDisplay_ss(app, handles)
            
            
            if isfield(handles,'debug') && handles.debug
              fprintf(1,'updataDisplay_ss\n');
              PrintStack(dbstack)
            end
            
            handles = updateDisplay_newvol(app, handles);
            return
            
            if isfield(handles.vols{handles.currentVol},'imgs')
              if size(handles.vols{handles.currentVol}.imgs,4)==3
                ima = squeeze(handles.vols{handles.currentVol}.imgs(:,:,handles.ss,:));
              else
                ima = squeeze(handles.vols{handles.currentVol}.imgs(:,:,handles.ss));
              end
            elseif isfield(handles.vols{handles.currentVol},'imgs1')
            %  ima = handles.vols{handles.currentVol}.imgs1{handles.ss}; % Should scale to size of baseline image
              ima = handles.vols{handles.currentVol}.imgs3{handles.ss};
            end
            
            set(handles.imCR, 'CData', ima);
            set(handles.StextSR, 'Position', [handles.ss handles.rrLim(1)]);
            set(handles.SlineSR, 'XData', [handles.ss handles.ss]);
            set(handles.StextCS, 'Position', [handles.ccLim(1) handles.ss]);
            set(handles.SlineCS, 'YData', [handles.ss handles.ss]);
            if handles.numSurfs > 0
              delete(handles.meshCrossSectionCR.lh);
            end
            handles = meshCrossSection_ss(app, handles);
            
            updateDisplay_zoom(app, handles);
        end
        
        function handles = updateDisplay_zoom(app, handles)
            
            if isfield(handles,'debug') && handles.debug
              fprintf(1,'updateDisplay_zoom\n');
              PrintStack(dbstack)
            end
            
            if handles.zoom < sqrt(eps)
            
              handles.rrLim = handles.rrLimOrig;
              handles.ccLim = handles.ccLimOrig;
              handles.ssLim = handles.ssLimOrig;
            
            else
            
              zoom = 2^handles.zoom;
              halfRange_mm = handles.halfRange_mm/zoom;
              tmp = [-halfRange_mm +halfRange_mm];
              handles.rrLim = handles.rrZoom + tmp/handles.voxSize(1);
              handles.ccLim = handles.ccZoom + tmp/handles.voxSize(2);
              handles.ssLim = handles.ssZoom + tmp/handles.voxSize(3);
            
            end
            
            if isfield(handles.vols{handles.currentVol},'imgs')
              set(handles.axesCR, 'Xlim', handles.ccLim);
              set(handles.axesCR, 'Ylim', handles.rrLim);
              set(handles.axesSR, 'Xlim', handles.ssLim);
              set(handles.axesSR, 'Ylim', handles.rrLim);
              set(handles.axesCS, 'Xlim', handles.ccLim);
              set(handles.axesCS, 'Ylim', handles.ssLim);
            
              set(handles.CtextCR, 'Position', [handles.cc handles.rrLim(1)]);
              set(handles.RtextCR, 'Position', [handles.ccLim(1) handles.rr]);
              set(handles.StextSR, 'Position', [handles.ss handles.rrLim(1)]);
              set(handles.RtextSR, 'Position', [handles.ssLim(1) handles.rr]);
              set(handles.CtextCS, 'Position', [handles.cc handles.ssLim(2)]);
              set(handles.StextCS, 'Position', [handles.ccLim(1) handles.ss]);
            elseif isfield(handles.vols{handles.currentVol},'imgs1')
              ima = handles.vols{handles.currentVol}.imgs3{handles.ss};
              set(handles.axesCR, 'Xlim', size(ima,2)/handles.ccMax*handles.ccLim); sfc = size(ima,2)/handles.ccMax;
              set(handles.axesCR, 'Ylim', size(ima,1)/handles.rrMax*handles.rrLim); sfr = size(ima,2)/handles.rrMax;
              ima = handles.vols{handles.currentVol}.imgs2{handles.cc};
              set(handles.axesSR, 'Xlim', size(ima,2)/handles.ssMax*handles.ssLim);
              set(handles.axesSR, 'Ylim', size(ima,1)/handles.rrMax*handles.rrLim); sfs = size(ima,2)/handles.ssMax;
              ima = handles.vols{handles.currentVol}.imgs1{handles.rr};
              set(handles.axesCS, 'Xlim', size(ima,2)/handles.ccMax*handles.ccLim);
              set(handles.axesCS, 'Ylim', size(ima,1)/handles.ssMax*handles.ssLim);
            
              set(handles.CtextCR, 'Position', [sfc*handles.cc sfr*handles.rrLim(1)]);
              set(handles.RtextCR, 'Position', [sfc*handles.ccLim(1) sfr*handles.rr]);
              set(handles.StextSR, 'Position', [sfs*handles.ss sfr*handles.rrLim(1)]);
              set(handles.RtextSR, 'Position', [sfs*handles.ssLim(1) sfr*handles.rr]);
              set(handles.CtextCS, 'Position', [sfc*handles.cc sfs*handles.ssLim(2)]);
              set(handles.StextCS, 'Position', [sfc*handles.ccLim(1) sfs*handles.ss]);
            
            %  keyboard
            end
        end
        
        function volPB_Callback(app, hObject, eventdata, handles)
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            tic
            
            if isnumeric(hObject)
              [minVal, chosenVol] = min(abs(handles.volPB - hObject));
            else
              [minVal, chosenVol] = min(cellfun(@(x)norm(x-hObject.Position),{handles.volPB(:).Position}'));
            end
            if chosenVol == handles.currentVol
              if 0 % sum(handles.hideVol) < handles.numVols-1 % Disable hiding volume (perhaps enable, using modifier keys)
                handles.hideVol(chosenVol) = 1;
                set(hObject, 'ForegroundColor', [0.5 0.5 0.5]);
                set(hObject, 'TooltipString', 'Show this volume');
                tmp = find(handles.hideVol == 0);
                tmpInd = find(tmp > handles.currentVol);
                if isempty(tmpInd)
                  tmpInd = 1;
                end
                handles.currentVol = tmp(tmpInd(1));
                handles.currentVolPB = handles.volPB(handles.currentVol);
              end
            else
              set(handles.currentVolPB, 'ForegroundColor', 'k');
              set(handles.currentVolPB, 'TooltipString', 'Show this volume');
              handles.hideVol(chosenVol) = 0;
              handles.currentVol = chosenVol;
              handles.currentVolPB = hObject;
            end
            set(handles.currentVolPB, 'ForegroundColor', 'r');
            set(handles.currentVolPB, 'TooltipString', 'Hide this volume');
            handles.Mvxl2lph = handles.vols{handles.currentVol}.Mvxl2lph;
            
            cVal = handles.cVal(handles.currentVol);
            set(handles.contrast_slider, 'Value', cVal);
            dVal = handles.dVal(handles.currentVol);
            set(handles.darken_slider, 'Value', dVal);
            handles = updateDisplay_clims(app, handles);
            
            handles = updateDisplay_newvol(app, handles);
            
            guidata(hObject, handles);
            
            if isfield(handles,'debug') && handles.debug
              toc
            end
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.orbit.ClickedCallback = @(~,~)setmodegui(hObj,hfig,'orbit');
            app.orbitscenelight.ClickedCallback = @(~,~)setmodegui(hObj,hfig,'orbitscenelight');
            app.pan.ClickedCallback = @(~,~)setmodegui(hObj,hfig,'pan');
            app.dollyhv.ClickedCallback = @(~,~)setmodegui(hObj,hfig,'dollyhv');
            app.dollyfb.ClickedCallback = @(~,~)setmodegui(hObj,hfig,'dollyfb');
            app.zoom.ClickedCallback = @(~,~)setmodegui(hObj,hfig,'zoom');
            app.roll.ClickedCallback = @(~,~)setmodegui(hObj,hfig,'roll');
            app.x.ClickedCallback = @(~,~)setcoordsys(hObj,hfig,'x');
            app.y.ClickedCallback = @(~,~)setcoordsys(hObj,hfig,'y');
            app.z.ClickedCallback = @(~,~)setcoordsys(hObj,hfig,'z');
            app.none.ClickedCallback = @(~,~)setcoordsys(hObj,hfig,'none');
            app.togglescenelight.ClickedCallback = @(~,~)togglescenelight(hObj,hfig);
            app.orthographic.ClickedCallback = @(~,~)setprojection(hObj,hfig,'orthographic');
            app.perspective.ClickedCallback = @(~,~)setprojection(hObj,hfig,'perspective');
            app.reset.ClickedCallback = @(~,~)resetcameraandscenelight(hObj,hfig);
            app.stopmoving.ClickedCallback = @(~,~)stopmoving(hObj);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function showVolMesh_OpeningFcn(app, varargin)
            %% ------------------------------------------------------------
            %% --- Executes just before showVolMesh is made visible.
            %% ------------------------------------------------------------
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            pos = [10 200 1200 900];
            set(handles.figure, 'Units', 'points');
            set(handles.figure, 'Position', pos);
            
            % Initialize figure
            if strcmp(version('-release'), '14')
              cameratoolbar('Close'); % this takes 1 second!
            else
              cameratoolbar('hide');
            end
            %cameratoolbar('close'); % this takes 1 second!
            %colormap(gray);
            set(handles.figure, 'Renderer', 'zbuffer');
            
            if isempty(varargin)
              fprintf('--------------------------------\n')
              fprintf('%s: no inputs given\n', mfilename);
              fprintf('--------------------------------\n')
              error('Exiting ...');
            end
            
            % Read in inputs
            
            %handles.debug = true; % AMD: enable debugging code
            handles.debug = false; % AMD: disable debugging code
            
            handles.LineWidth = 3;
            handles.numVols = 0;
            handles.numSurfs = 0;
            handles.initialLPH = [];
            handles.initialRCS = [];
            handles.crange = [];
            handles.cmap = gray;
            
            handles = parse_arguments(app, handles,varargin);
            
            % ToDos AMD
            %   Handle different data types:
            %     3- or 4-d volumes
            %     individual ctx_vols
            %     cell arrays of 3- or 4-d volumes
            %     cell arrays of ctx_vols
            %     individual surface structs
            %     cell arrays of surface structs
            %   Make function to handle each element of array
            
            
            % Check that we have at least one vol
            if handles.numVols == 0
              fprintf('--------------------------------\n')
              fprintf('%s: must have at least one vol struct input\n', ...
            	  mfilename);
              fprintf('--------------------------------\n')
              error('Exiting ...');
            end
            
            % Load up from the first vol
            handles.Mvxl2lph = handles.vols{1}.Mvxl2lph;
            
            % Error checking I: all vols must have the same voxel number
            voxNumCheck = [];
            for vvv = 1:handles.numVols
              if isfield(handles.vols{vvv},'imgs')
                voxNumCheck = [voxNumCheck; size(handles.vols{vvv}.imgs(:,:,:,1))];
              end
            end
            if 1 % (handles.numVols == 1) || (sum(range(voxNumCheck)) < eps)
              handles.rrMax = voxNumCheck(1,1);
              handles.ccMax = voxNumCheck(1,2);
              handles.ssMax = voxNumCheck(1,3);
            else
              fprintf('--------------------------------\n')
              fprintf('%s: all the vols must have the same voxel number\n', ...
            	  mfilename);
              fprintf('--------------------------------\n')
              error('Exiting ...');
            end
            
            % Error checking II: all vols must have the same voxel size
            voxSizeCheck = [];
            for vvv = 1:handles.numVols
              voxSizeCheck = [voxSizeCheck;
            		  [norm(handles.vols{vvv}.Mvxl2lph(:,1)) ...
            		   norm(handles.vols{vvv}.Mvxl2lph(:,2)) ...
            		   norm(handles.vols{vvv}.Mvxl2lph(:,3))];
            		 ];
            end
            if (handles.numVols == 1) || (sum(range(voxSizeCheck)) < 1e-3)
              handles.voxSize = voxSizeCheck(1,:);
              if ( (handles.voxSize(1) < eps) | ...
                   (handles.voxSize(2) < eps) | ...
                   (handles.voxSize(3) < eps) )
                fprintf('--------------------------------\n')
                fprintf('%s: bad info in Mvxl2lph\n', mfilename);
                fprintf('--------------------------------\n')
                error('Exiting ...');
              end
            else
              fprintf('--------------------------------\n')
              fprintf('%s: all the vols must have the same voxel size\n', ...
            	  mfilename);
              fprintf('--------------------------------\n')
              error('Exiting ...');
            end
            
            % Contrast info
            maxI = zeros(handles.numVols,1);
            minI = zeros(handles.numVols,1);
            for vvv = 1:handles.numVols
              if isfield(handles.vols{vvv}, 'crange')
                minI(vvv) = handles.vols{vvv}.crange(1);
                maxI(vvv) = handles.vols{vvv}.crange(2);
              end
            end
            handles.maxI = maxI;
            handles.minI = minI;
            handles.CLim = [minI maxI+eps];
            
            
            % AMD: should change contrast controls to standard window/level?
            
            handles.cVal = ones(handles.numVols,1);
            set(handles.contrast_slider, 'Value', 1.0);
            set(handles.contrast_slider,   'Min', 0.5);
            for vvv = 1:handles.numVols
              if isfield(handles.vols{vvv}, 'crange')
                minI(vvv) = handles.vols{vvv}.crange(1);
                maxI(vvv) = handles.vols{vvv}.crange(2);
              else
                if ~isempty(handles.crange)
                  minI(vvv) = handles.crange(1);
                  maxI(vvv) = handles.crange(2);
                elseif isfield(handles.vols{vvv},'imgs')
                  if exist('maxmin')
                    [maxI(vvv), minI(vvv)] = maxmin(handles.vols{vvv}.imgs);
                  else
                    maxI(vvv) = max(handles.vols{vvv}.imgs(:));
                    minI(vvv) = min(handles.vols{vvv}.imgs(:));
                  end
                end
              end
            end
            handles.maxI = maxI;
            handles.minI = minI;
            handles.CLim = [minI maxI+eps];
            
            
            % AMD: should change contrast controls to standard window/level?
            
            handles.cVal = ones(handles.numVols,1);
            set(handles.contrast_slider, 'Value', 1.0);
            set(handles.contrast_slider,   'Min', 0.5);
            set(handles.contrast_slider,   'Max', 1.5);
            
            handles.dVal = 0.5*ones(handles.numVols,1);
            set(handles.darken_slider, 'Value', 0.5);
            set(handles.darken_slider,   'Min', 0);
            set(handles.darken_slider,   'Max', 1);
            
            % Starting points
            handles.rr = ceil(handles.rrMax/2 + sqrt(eps));
            handles.cc = ceil(handles.ccMax/2 + sqrt(eps));
            handles.ss = ceil(handles.ssMax/2 + sqrt(eps));
            handles.rrStored = handles.rr;
            handles.ccStored = handles.cc;
            handles.ssStored = handles.ss;
            
            str = sprintf('Store this voxel -- keyboard shortcut: p');
            set(handles.storePoint,'TooltipString', str);
            str = sprintf('Go to voxel (%d,%d,%d) -- keyboard shortcut: ;', ...
            	      handles.rrStored, handles.ccStored, handles.ssStored);
            set(handles.gotoPoint,'TooltipString', str);
            str = sprintf('Swap current voxel with voxel (%d,%d,%d) -- keyboard shortcut: /', ...
            	      handles.rrStored, handles.ccStored, handles.ssStored);
            set(handles.swapPoint,'TooltipString', str);
            
            % Zoom info
            handles.zoom = 0;
            set(handles.zoom_slider, 'Value', 0);
            set(handles.zoom_slider, 'Min', 0);
            set(handles.zoom_slider, 'Max', 6);
            
            tmp_rr = (handles.rr - 0.5) * handles.voxSize(1);
            tmp_cc = (handles.cc - 0.5) * handles.voxSize(2);
            tmp_ss = (handles.ss - 0.5) * handles.voxSize(3);
            handles.halfRange_mm = max(max(tmp_rr, tmp_cc), tmp_ss);
            
            tmp = [-handles.halfRange_mm +handles.halfRange_mm];
            handles.rrLimOrig = handles.rr + tmp/handles.voxSize(1);
            handles.ccLimOrig = handles.cc + tmp/handles.voxSize(2);
            handles.ssLimOrig = handles.ss + tmp/handles.voxSize(3);
            
            handles.rrZoom = handles.rr;
            handles.ccZoom = handles.cc;
            handles.ssZoom = handles.ss;
            
            %% ------------------------------------------------------------
            % Initialize meshes
            
            handles.showSurf = ones(handles.numSurfs, 1);
            
            % For each given mesh
            Mlph2vxl = inv(handles.Mvxl2lph);
            %str = 'showVolMesh(''meshPB_Callback'',gcbo,[],guidata(gcbo))';
            str = sprintf('%s(''meshPB_Callback'',gcbo,[],guidata(gcbo))',mfilename);
            for mmm = 1:handles.numSurfs
            
              % Convert surfaces vertex coords from LPH to RCS
              tmp = handles.surfs(mmm).vertices;
              tmp(:,4) = 1;
              tmp = tmp*transpose(Mlph2vxl);
              handles.surfs(mmm).vertices = tmp(:,1:3);
            
              % Assign pushbutton for each mesh, if not given
              handles.meshPB(mmm) = uicontrol('Tag', 'meshButton', ...
            				  'Style', 'pushbutton', ...
            				  'Unit', 'characters', ...
            				  'Position', [10 10 10 10], ...
            				  'ForegroundColor', handles.surfs(mmm).color, ...
            				  'TooltipString', 'Hide this mesh', ...
            				  'String', ['mesh' num2str(mmm)]);
              set(handles.meshPB(mmm), 'Callback', str);
            
            end
            
            %% ------------------------------------------------------------
            % Initial display
            
            %str = 'showVolMesh(''volPB_Callback'',gcbo,[],guidata(gcbo))';
            str = sprintf('%s(''volPB_Callback'',gcbo,[],guidata(gcbo))',mfilename);
            for vvv = 1:handles.numVols
              handles.volPB(vvv) = uicontrol('Tag', 'volButton', ...
            				 'Style', 'pushbutton', ...
            				 'Unit', 'characters', ...
            				 'Position', [10 10 10 10], ...
            				 'TooltipString', 'Show this volume', ...
            				 'String', ['vol' num2str(vvv)]);
              set(handles.volPB(vvv), 'Callback', str);
            end
            handles.hideVol = zeros(handles.numVols, 1);
            handles.currentVol = 1;
            handles.currentVolPB = handles.volPB(1);
            set(handles.currentVolPB, 'ForegroundColor', 'r');
            set(handles.currentVolPB, 'TooltipString', 'Hide this volume');
            handles.ORIENTATION = 1;
            handles.HIDE1 = 0;
            handles.HIDE2 = 0;
            handles.HIDE3 = 0;
            handles = displayNewOrientation(app, handles);
            printInfo(app, handles);
            handles.output = hObject;
            guidata(hObject, handles);
            figure_ResizeFcn(hObject, eventdata, handles);
            
            if ~isempty(handles.initialRCS)
              handles.initialLPH = inv(Mlph2vxl) * colvec(handles.initialRCS);
            end
            
            if ~isempty(handles.initialLPH)
              handles = gotoPointLPH(app, handles, handles.initialLPH);
              printInfo(app, handles);
              guidata(hObject, handles);
            end
        end

        % Button pushed function: contrast_reset
        function contrast_reset_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            set(handles.contrast_slider, 'Value', 1);
            handles.cVal(handles.currentVol) = 1;
            handles = updateDisplay_clims(app, handles);
            guidata(hObject, handles);
        end

        % Value changed function: contrast_slider
        function contrast_slider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            cVal = get(handles.contrast_slider, 'Value');
            handles.cVal(handles.currentVol) = cVal;
            
            modifiers = get(gcf,'currentModifier');
            if ~isempty(modifiers)
              fprintf(1,'modifiers:\n')
              disp(modifiers)
              if ismember('shift',modifiers) | ismember('control',modifiers)
                handles.cVal(:) = cVal;
              end
            end
            
            handles = updateDisplay_clims(app, handles);
            guidata(hObject, handles);
        end

        % Button pushed function: cycleOrientations1
        function cycleOrientations1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            handles = cycleOrientationsForward(app, handles);
            guidata(hObject, handles);
        end

        % Button pushed function: cycleOrientations2
        function cycleOrientations2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles = cycleOrientationsReverse(app, handles);
            guidata(hObject, handles);
        end

        % Button pushed function: cycleVolumes_1
        function cycleVolumes_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            handles = cycleVolumes(app, handles);
            guidata(hObject, handles);
        end

        % Button pushed function: darken_reset
        function darken_reset_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            set(handles.darken_slider, 'Value', 0.5);
            handles.dVal(handles.currentVol) = 0.5;
            handles = updateDisplay_clims(app, handles);
            guidata(hObject, handles);
        end

        % Value changed function: darken_slider
        function darken_slider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            dVal = get(handles.darken_slider, 'Value');
            handles.dVal(handles.currentVol) = dVal;
            
            modifiers = get(gcf,'currentModifier');
            if ~isempty(modifiers)
              fprintf(1,'modifiers:\n')
              disp(modifiers)
              if ismember('shift',modifiers) | ismember('control',modifiers)
                handles.dVal(:) = dVal;
              end
            end
            
            handles = updateDisplay_clims(app, handles);
            guidata(hObject, handles);
        end

        % Button pushed function: decrementColumnNumber_1
        function decrementColumnNumber_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            handles = decrementColumnNumber(app, handles);
            guidata(hObject, handles);
        end

        % Button pushed function: decrementRowNumber_1
        function decrementRowNumber_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            handles = decrementRowNumber(app, handles);
            guidata(hObject, handles);
        end

        % Button pushed function: decrementSliceNumber_1
        function decrementSliceNumber_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            handles = decrementSliceNumber(app, handles);
            guidata(hObject, handles);
        end

        % Value changed function: edit_cc
        function edit_cc_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            cc = round(str2double(get(hObject,'String')));
            if isfinite(cc)
              if (cc >= 1) & (cc <= handles.ccMax)
                handles.cc = cc;
                handles = updateDisplay_cc(app, handles);
                printInfo(app, handles);
                guidata(hObject, handles);
              end
            end
            set(hObject,'String',num2str(handles.cc));
        end

        % Value changed function: edit_command
        function edit_command_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            str = get(hObject, 'String');
            set(handles.text_last_echo, 'String', str);
            set(hObject, 'String', '');
            handles = parseCommand(app, handles, str);
            guidata(hObject, handles);
        end

        % Value changed function: edit_rr
        function edit_rr_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            rr = round(str2double(get(hObject,'String')));
            if isfinite(rr)
              if (rr >= 1) & (rr <= handles.rrMax)
                handles.rr = rr;
                handles = updateDisplay_rr(app, handles);
                printInfo(app, handles);
                guidata(hObject, handles);
              end
            end
            set(hObject,'String',num2str(handles.rr));
        end

        % Value changed function: edit_ss
        function edit_ss_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            ss = round(str2double(get(hObject,'String')));
            if isfinite(ss)
              if (ss >= 1) & (ss <= handles.ssMax)
                handles.ss = ss;
                handles = updateDisplay_ss(app, handles);
                printInfo(app, handles);
                guidata(hObject, handles);
              end
            end
            set(hObject,'String',num2str(handles.ss));
        end

        % Key press function: figure
        function figure_KeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            key = lower(get(hObject, 'CurrentCharacter'));
            switch key
            
             case 'c'
              handles = cycleVolumesMovie(app, handles, 10, 0.1);
            
             case 'v'
              handles = cycleVolumes(app, handles);
            
             case 'o'
              handles = cycleOrientationsForward(app, handles);
            
             case 't'
              handles = toggleLines1(app, handles);
            
             case handles.rrDecrChar
              handles = decrementRowNumber(app, handles);
            
             case handles.rrIncrChar
              handles = incrementRowNumber(app, handles);
            
             case handles.ccDecrChar
              handles = decrementColumnNumber(app, handles);
            
             case handles.ccIncrChar
              handles = incrementColumnNumber(app, handles);
            
             case handles.ssDecrChar
              handles = decrementSliceNumber(app, handles);
            
             case handles.ssIncrChar
              handles = incrementSliceNumber(app, handles);
            
             case {'p', ' '}
              handles = storeCurrentPoint(app, handles);
              set(handles.storePoint, 'Value', 1);
              pause(0.05);
              set(handles.storePoint, 'Value', 0);
            
             case ';'
              handles = gotoStoredPoint(app, handles);
              set(handles.gotoPoint, 'Value', 1);
              pause(0.05);
              set(handles.gotoPoint, 'Value', 0);
            
             case '/'
              handles = swapCurrentAndStoredPoints(app, handles);
              set(handles.swapPoint, 'Value', 1);
              pause(0.05);
              set(handles.swapPoint, 'Value', 0);
            
             case 'q'
              cVal = handles.cVal(handles.currentVol) - 0.1;
              cVal = max(cVal, get(handles.contrast_slider, 'Min'));
              handles.cVal(handles.currentVol) = cVal;
              set(handles.contrast_slider, 'Value', cVal);
              handles = updateDisplay_clims(app, handles);
            
             case 'w'
              cVal = handles.cVal(handles.currentVol) + 0.1;
              cVal = min(cVal, get(handles.contrast_slider, 'Max'));
              handles.cVal(handles.currentVol) = cVal;
              set(handles.contrast_slider, 'Value', cVal);
              handles = updateDisplay_clims(app, handles);
            
             case 'a'
              dVal = handles.dVal(handles.currentVol) - 0.1;
              dVal = max(dVal, get(handles.darken_slider, 'Min'));
              handles.dVal(handles.currentVol) = dVal;
              set(handles.darken_slider, 'Value', dVal);
              handles = updateDisplay_clims(app, handles);
            
             case 's'
              dVal = handles.dVal(handles.currentVol) + 0.1;
              dVal = min(dVal, get(handles.darken_slider, 'Max'));
              handles.dVal(handles.currentVol) = dVal;
              set(handles.darken_slider, 'Value', dVal);
              handles = updateDisplay_clims(app, handles);
            
             case 'z'
              zoom = handles.zoom - 0.6;
              zoom = max(zoom, get(handles.zoom_slider, 'Min'));
              handles.zoom = zoom;
              set(handles.zoom_slider, 'Value', zoom);
              handles.rrZoom = handles.rr;
              handles.ccZoom = handles.cc;
              handles.ssZoom = handles.ss;
              handles = updateDisplay_zoom(app, handles);
            
             case 'x'
              zoom = handles.zoom + 0.6;
              zoom = min(zoom,  get(handles.zoom_slider, 'Max'));
              handles.zoom = zoom;
              set(handles.zoom_slider, 'Value', zoom);
              handles.rrZoom = handles.rr;
              handles.ccZoom = handles.cc;
              handles.ssZoom = handles.ss;
              handles = updateDisplay_zoom(app, handles);
            
              case '!' % AMD: Save snapshot of main window
                shot = getframe(handles.axes1);
                if ~isfield(handles,'screenshotnum')
                  handles.screenshotnum = 0;
                end
                handles.screenshotnum = handles.screenshotnum+1;
                fname_out = sprintf('ScreenShot%03d.png',handles.screenshotnum);
                imwrite(shot.cdata,fname_out);
                fprintf(1,'file %s written\n',fname_out);
            
            end
            
            guidata(hObject, handles);
        end

        % Size changed function: figure
        function figure_ResizeFcn(app, event)
            %% ------------------------------------------------------------
            %% --- Executes when figure window is resized.
            %% ------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if isempty(handles)
              return;
            end
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            % Set up resize function
            set(handles.figure, 'Units', 'centimeters');
            figpos_cm = get(handles.figure, 'Position');
            set(handles.figure, 'Units', 'characters');
            figpos_ch = get(handles.figure, 'Position');
            handles.chFactor = figpos_ch(4)/figpos_ch(3)*figpos_cm(3)/figpos_cm(4);
            
            %% ---------------------------------------
            lB = 6;       % left border
            tB = 1;       % top border
            bB = 10;      % bottom border
            rB = 4;       % right border
            
            tAx123 = 1;   % vertical space above axes1, axes2 and axes3
            rAx1 = 7;     % horizontal space right of axes1
            rAx23 = 3;    % horizontal space right of axes2 and axes3
            sAx2Ax3 = 2;  % vertical space between axes2 and axes3
            
            wPBt = 7;     % width of topmost panel pushbuttons
            hPBt = 2;     % height of topmost panel pushbuttons
            sPBt = 1;     % horizontal space between topmost panel pushbuttons
            
            wPBr = 10;    % width of rightmost panel pushbuttons
            hPBr = 2;     % height of rightmost panel pushbuttons
            sPBr = 0;     % vertical space between rightmost panel pushbuttons
            
            ax1MinH = 45; % don't let ax1 height get smaller than this
            
            if (isfield(handles, 'numSurfs'))
            if handles.numSurfs == 0
              rB = 6;
              rAx23 = 0;
              wPBr = 0;
            end
            end
            
            figPos_orig = get(handles.figure, 'Position');
            W_orig = figPos_orig(3);
            H_orig = figPos_orig(4);
            %% ---------------------------------------
            
            %% Compute the axes sizes for a window of the current size
            H_padding = bB + tAx123 + hPBt + tB;
            W_padding = lB + rAx1 + rAx23 + wPBr + rB;
            ax1_h = max(H_orig - H_padding, ax1MinH);
            ax1_w = ax1_h/handles.chFactor;
            ax23_h = (ax1_h - sAx2Ax3)/2;
            ax23_w = ax23_h/handles.chFactor;
            
            %% ------------------------------------------------------------
            set(handles.axes1, 'Position', [lB bB ax1_w ax1_h]);
            tmp1 = lB + ax1_w + rAx1;
            set(handles.axes3, 'Position', [tmp1 bB ax23_w ax23_h]);
            tmp2 = bB + ax23_h + sAx2Ax3;
            set(handles.axes2, 'Position', [tmp1 tmp2 ax23_w ax23_h]);
            
            %% ------------------------------------------------------------
            bXO_w = 3.5;
            bXO_h = 1.5;
            tmp1 = lB + ax1_w - bXO_w;
            tmp2 = bB + ax1_h - bXO_h;
            set(handles.toggleLines1, 'Position', [tmp1 tmp2 bXO_w bXO_h]);
            tmp1 = lB + ax1_w + rAx1 + ax23_w - bXO_w;
            tmp2 = bB + ax1_h - bXO_h;
            set(handles.toggleLines2, 'Position', [tmp1 tmp2 bXO_w bXO_h]);
            tmp2 = bB + ax23_h - bXO_h;
            set(handles.toggleLines3, 'Position', [tmp1 tmp2 bXO_w bXO_h]);
            tmp2 = bB + ax23_h + sAx2Ax3;
            set(handles.cycleOrientations1, 'Position', [tmp1 tmp2 bXO_w bXO_h]);
            tmp2 = bB;
            set(handles.cycleOrientations2, 'Position', [tmp1 tmp2 bXO_w bXO_h]);
            
            %% ------------------------------------------------------------
            tmp1 = lB;
            tmp2 = bB + ax1_h + tAx123;
            set(handles.cycleVolumes, 'Position', [tmp1 tmp2 wPBt hPBt]);
            if isfield(handles, 'volPB')
              for vvv = 1:length(handles.volPB)
                tmp1 = tmp1 + wPBt + sPBt;
                set(handles.volPB(vvv), 'Position', [tmp1 tmp2 wPBt hPBt]);
              end
              tmp1 = lB + wPBt + sPBt/2;
              tmp2 = bB + ax1_h + tAx123/2;
              tmp3 = length(handles.volPB)*(wPBt + sPBt) + sPBt/6;
              tmp4 = hPBt + tAx123;
              set(handles.volFrame, 'Position', [tmp1 tmp2 tmp3 tmp4]);
            end
            
            %% ------------------------------------------------------------
            tmp1 = lB + ax1_w + rAx1 + ax23_w + rAx23;
            tmp2 = bB + ax1_h - hPBr;
            if isfield(handles, 'meshPB')
              for mmm = 1:length(handles.meshPB)
                set(handles.meshPB(mmm), 'Position', [tmp1 tmp2 wPBr hPBr]);
                tmp2 = tmp2 - hPBr - sPBr;
              end
            end
            
            %% ------------------------------------------------------------
            switch version('-release')
             case '13',
              backGray = [0.7 0.7 0.7];
              liteGray = [0.85 0.85 0.85];
              fontSize = 10;
             case '14',
              backGray = [0.69 0.71 0.70]*0.97;
              liteGray = [0.84 0.86 0.85]*0.97;
              fontSize = 9;
             otherwise,
              backGray = [0.69 0.71 0.70]*0.97;
              liteGray = [0.84 0.86 0.85]*0.97;
              fontSize = 9;
            end
            
            set(handles.figure, 'Color', [0.7 0.7 0.7]);
            
            set(handles.toggleLines1, 'BackgroundColor', backGray);
            set(handles.toggleLines2, 'BackgroundColor', backGray);
            set(handles.toggleLines3, 'BackgroundColor', backGray);
            set(handles.cycleOrientations1, 'BackgroundColor', backGray);
            set(handles.cycleOrientations2, 'BackgroundColor', backGray);
            set(handles.cycleVolumes, 'BackgroundColor', backGray);
            if (isfield(handles, 'volPB'))
                set(handles.volPB, 'BackgroundColor', backGray);
            end
            %set(handles.volPB, 'BackgroundColor', backGray);
            set(handles.volFrame, 'BackgroundColor', backGray);
            if isfield(handles, 'meshPB')
              set(handles.meshPB, 'BackgroundColor', backGray);
            end
            set(handles.frame5, 'BackgroundColor', backGray);
            set(handles.contrast_reset, 'BackgroundColor', backGray);
            set(handles.darken_reset, 'BackgroundColor', backGray);
            set(handles.zoom_reset, 'BackgroundColor', backGray);
            set(handles.contrast_slider, 'BackgroundColor', backGray);
            set(handles.darken_slider, 'BackgroundColor', backGray);
            set(handles.zoom_slider, 'BackgroundColor', backGray);
            set(handles.frame4, 'BackgroundColor', backGray);
            set(handles.text_last, 'BackgroundColor', backGray);
            set(handles.text_last_echo, 'BackgroundColor', backGray);
            set(handles.text_command, 'BackgroundColor', backGray);
            set(handles.frame3, 'BackgroundColor', backGray);
            set(handles.text_voxvalstr, 'BackgroundColor', backGray);
            set(handles.text_voxval, 'BackgroundColor', backGray);
            set(handles.text_scrvalstr, 'BackgroundColor', backGray);
            set(handles.text_scrval, 'BackgroundColor', backGray);
            set(handles.frame6, 'BackgroundColor', backGray);
            set(handles.storePoint, 'BackgroundColor', backGray);
            set(handles.gotoPoint, 'BackgroundColor', backGray);
            set(handles.swapPoint, 'BackgroundColor', backGray);
            set(handles.frame2, 'BackgroundColor', backGray);
            set(handles.text_L, 'BackgroundColor', backGray);
            set(handles.text_ll, 'BackgroundColor', backGray);
            set(handles.text_P, 'BackgroundColor', backGray);
            set(handles.text_pp, 'BackgroundColor', backGray);
            set(handles.text_H, 'BackgroundColor', backGray);
            set(handles.text_hh, 'BackgroundColor', backGray);
            set(handles.frame1, 'BackgroundColor', backGray);
            set(handles.text_row, 'BackgroundColor', backGray);
            set(handles.decrementRowNumber, 'BackgroundColor', backGray);
            set(handles.incrementRowNumber, 'BackgroundColor', backGray);
            set(handles.text_col, 'BackgroundColor', backGray);
            set(handles.decrementColumnNumber, 'BackgroundColor', backGray);
            set(handles.incrementColumnNumber, 'BackgroundColor', backGray);
            set(handles.text_slice, 'BackgroundColor', backGray);
            set(handles.decrementSliceNumber, 'BackgroundColor', backGray);
            set(handles.incrementSliceNumber, 'BackgroundColor', backGray);
            
            set(handles.edit_command, 'BackgroundColor', liteGray); %%%
            set(handles.edit_rr, 'BackgroundColor', liteGray);
            set(handles.edit_cc, 'BackgroundColor', liteGray);
            set(handles.edit_ss, 'BackgroundColor', liteGray);
            
            set(handles.cycleVolumes, 'FontSize', fontSize);
            if (isfield(handles, 'volPB'))
                set(handles.volPB, 'FontSize', fontSize);
            end
            
            if isfield(handles, 'meshPB')
              set(handles.meshPB, 'FontSize', fontSize);
            end
            set(handles.contrast_reset, 'FontSize', fontSize);
            set(handles.darken_reset, 'FontSize', fontSize);
            set(handles.zoom_reset, 'FontSize', fontSize);
            set(handles.text_last, 'FontSize', fontSize);
            set(handles.text_last_echo, 'FontSize', fontSize);
            set(handles.text_command, 'FontSize', fontSize);
            set(handles.text_voxvalstr, 'FontSize', fontSize);
            set(handles.text_voxval, 'FontSize', fontSize);
            set(handles.text_scrvalstr, 'FontSize', fontSize);
            set(handles.text_scrval, 'FontSize', fontSize);
            set(handles.storePoint, 'FontSize', fontSize);
            set(handles.gotoPoint, 'FontSize', fontSize);
            set(handles.swapPoint, 'FontSize', fontSize);
            set(handles.text_L, 'FontSize', fontSize);
            set(handles.text_ll, 'FontSize', fontSize);
            set(handles.text_P, 'FontSize', fontSize);
            set(handles.text_pp, 'FontSize', fontSize);
            set(handles.text_H, 'FontSize', fontSize);
            set(handles.text_hh, 'FontSize', fontSize);
            set(handles.text_row, 'FontSize', fontSize);
            set(handles.decrementRowNumber, 'FontSize', fontSize);
            set(handles.incrementRowNumber, 'FontSize', fontSize);
            set(handles.text_col, 'FontSize', fontSize);
            set(handles.decrementColumnNumber, 'FontSize', fontSize);
            set(handles.incrementColumnNumber, 'FontSize', fontSize);
            set(handles.text_slice, 'FontSize', fontSize);
            set(handles.decrementSliceNumber, 'FontSize', fontSize);
            set(handles.incrementSliceNumber, 'FontSize', fontSize);
            
            set(handles.edit_command, 'FontSize', fontSize); %%%
            set(handles.edit_rr, 'FontSize', fontSize);
            set(handles.edit_cc, 'FontSize', fontSize);
            set(handles.edit_ss, 'FontSize', fontSize);
            
            set(handles.figure, 'Units', 'characters');
            %fprintf(1,'Position in characters: %s\n',num2str(round(get(handles.figure,'position')),'%d '));
            set(handles.figure, 'Units', 'points');
            %fprintf(1,'Position in points: %s\n',num2str(round(get(handles.figure,'position')),'%d '));
            %disp(get(handles.figure,'position'))
            
            set(handles.figure, 'MenuBar', 'none');
            set(handles.figure, 'ToolBar', 'none');
        end

        % Window button down function: figure
        function figure_WindowButtonDownFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Here, we hack around the fact that MATLAB R13 v6.5 does not seem to
            % trap the axes button down callback -- instead, this is caught by the
            % figure button down callback. So the code belows figures out which
            % axes you clicked in, and calls the appropriate axes callback.
            
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            pos_axCR = get(handles.axesCR,'CurrentPoint');
            XLim_axCR = get(handles.axesCR, 'XLim');
            YLim_axCR = get(handles.axesCR, 'YLim');
            
            pos_axSR = get(handles.axesSR,'CurrentPoint');
            XLim_axSR = get(handles.axesSR, 'XLim');
            YLim_axSR = get(handles.axesSR, 'YLim');
            
            pos_axCS = get(handles.axesCS,'CurrentPoint');
            XLim_axCS = get(handles.axesCS, 'XLim');
            YLim_axCS = get(handles.axesCS, 'YLim');
            
            if     ((pos_axCR(1,1) >= XLim_axCR(1)) & ...
            	(pos_axCR(1,1) <= XLim_axCR(2)) & ...
            	(pos_axCR(1,2) >= YLim_axCR(1)) & ...
            	(pos_axCR(1,2) <= YLim_axCR(2)))
              axesCR_ButtonDownFcn(app, handles.axesCR, eventdata, handles);
            elseif ((pos_axSR(1,1) >= XLim_axSR(1)) & ...
            	(pos_axSR(1,1) <= XLim_axSR(2)) & ...
            	(pos_axSR(1,2) >= YLim_axSR(1)) & ...
            	(pos_axSR(1,2) <= YLim_axSR(2)))
              axesSR_ButtonDownFcn(app, handles.axesSR, eventdata, handles);
            elseif ((pos_axCS(1,1) >= XLim_axCS(1)) & ...
            	(pos_axCS(1,1) <= XLim_axCS(2)) & ...
            	(pos_axCS(1,2) >= YLim_axCS(1)) & ...
            	(pos_axCS(1,2) <= YLim_axCS(2)))
              axesCS_ButtonDownFcn(app, handles.axesCS, eventdata, handles);
            end
        end

        % Value changed function: gotoPoint
        function gotoPoint_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            handles = gotoStoredPoint(app, handles);
            guidata(hObject, handles);
        end

        % Button pushed function: incrementColumnNumber_1
        function incrementColumnNumber_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            handles = incrementColumnNumber(app, handles);
            guidata(hObject, handles);
        end

        % Button pushed function: incrementRowNumber_1
        function incrementRowNumber_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            handles = incrementRowNumber(app, handles);
            guidata(hObject, handles);
        end

        % Button pushed function: incrementSliceNumber_1
        function incrementSliceNumber_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            handles = incrementSliceNumber(app, handles);
            guidata(hObject, handles);
        end

        % Value changed function: storePoint
        function storePoint_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            handles = storeCurrentPoint(app, handles);
            guidata(hObject, handles);
        end

        % Value changed function: swapPoint
        function swapPoint_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            handles = swapCurrentAndStoredPoints(app, handles);
            guidata(hObject, handles);
        end

        % Value changed function: toggleLines1_1
        function toggleLines1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            handles = toggleLines1(app, handles);
            guidata(hObject, handles);
        end

        % Value changed function: toggleLines2_1
        function toggleLines2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            handles = toggleLines2(app, handles);
            guidata(hObject, handles);
        end

        % Value changed function: toggleLines3_1
        function toggleLines3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            handles = toggleLines3(app, handles);
            guidata(hObject, handles);
        end

        % Button pushed function: zoom_reset
        function zoom_reset_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            handles.zoom = 0;
            set(handles.zoom_slider, 'Value', 0);
            handles = updateDisplay_zoom(app, handles);
            guidata(hObject, handles);
        end

        % Value changed function: zoom_slider
        function zoom_slider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if isfield(handles,'debug') && handles.debug
              PrintStack(dbstack)
            end
            
            handles.zoom = get(hObject,'Value');
            handles.rrZoom = handles.rr;
            handles.ccZoom = handles.cc;
            handles.ssZoom = handles.ss;
            handles = updateDisplay_zoom(app, handles);
            guidata(hObject, handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure = uifigure('Visible', 'off');
            colormap(app.figure, 'parula');
            app.figure.Name = 'showVol';
            app.figure.SizeChangedFcn = createCallbackFcn(app, @figure_ResizeFcn, true);
            app.figure.WindowButtonDownFcn = createCallbackFcn(app, @figure_WindowButtonDownFcn, true);
            app.figure.KeyPressFcn = createCallbackFcn(app, @figure_KeyPressFcn, true);
            app.figure.Tag = 'figure';
            app.CameraToolBar = uitoolbar(app.figure);
            app.CameraToolBar.Tag = 'CameraToolBar';
            app.orbit = uitoggletool(app.CameraToolBar);
            app.orbit.Tag = 'orbit';
            app.orbit.Tooltip = 'Orbit Camera';
            app.orbit.Icon = 'orbit_image.png';
            app.orbitscenelight = uitoggletool(app.CameraToolBar);
            app.orbitscenelight.Tag = 'orbitscenelight';
            app.orbitscenelight.Tooltip = 'Orbit Scene Light';
            app.orbitscenelight.Icon = 'orbitscenelight_image.png';
            app.pan = uitoggletool(app.CameraToolBar);
            app.pan.Tag = 'pan';
            app.pan.Tooltip = 'Pan/Tilt Camera';
            app.pan.Icon = 'pan_image.png';
            app.dollyhv = uitoggletool(app.CameraToolBar);
            app.dollyhv.Tag = 'dollyhv';
            app.dollyhv.Tooltip = 'Move Camera Horizontally/Vertically';
            app.dollyhv.Icon = 'dollyhv_image.png';
            app.dollyfb = uitoggletool(app.CameraToolBar);
            app.dollyfb.Tag = 'dollyfb';
            app.dollyfb.Tooltip = 'Move Camera Forward/Back';
            app.dollyfb.Icon = 'dollyfb_image.png';
            app.zoom = uitoggletool(app.CameraToolBar);
            app.zoom.Tag = 'zoom';
            app.zoom.Tooltip = 'Zoom Camera';
            app.zoom.Icon = 'zoom_image.png';
            app.roll = uitoggletool(app.CameraToolBar);
            app.roll.Tag = 'roll';
            app.roll.Tooltip = 'Roll Camera';
            app.roll.Icon = 'roll_image.png';
            app.x = uitoggletool(app.CameraToolBar);
            app.x.Tag = 'x';
            app.x.Tooltip = 'Principal Axis X';
            app.x.Enable = 'off';
            app.x.Icon = 'x_image.png';
            app.x.Separator = 'on';
            app.y = uitoggletool(app.CameraToolBar);
            app.y.Tag = 'y';
            app.y.Tooltip = 'Principal Axis Y';
            app.y.Enable = 'off';
            app.y.Icon = 'y_image.png';
            app.z = uitoggletool(app.CameraToolBar);
            app.z.Tag = 'z';
            app.z.Tooltip = 'Principal Axis Z';
            app.z.Enable = 'off';
            app.z.Icon = 'z_image.png';
            app.none = uitoggletool(app.CameraToolBar);
            app.none.Tag = 'none';
            app.none.Tooltip = 'No Principal Axis';
            app.none.Enable = 'off';
            app.none.Icon = 'none_image.png';
            app.togglescenelight = uipushtool(app.CameraToolBar);
            app.togglescenelight.Tag = 'togglescenelight';
            app.togglescenelight.Tooltip = 'Toggle Scene Light';
            app.togglescenelight.Icon = 'togglescenelight_image.png';
            app.togglescenelight.Separator = 'on';
            app.orthographic = uipushtool(app.CameraToolBar);
            app.orthographic.Tag = 'orthographic';
            app.orthographic.Tooltip = 'Orthographic Projection';
            app.orthographic.Icon = 'orthographic_image.png';
            app.orthographic.Separator = 'on';
            app.perspective = uipushtool(app.CameraToolBar);
            app.perspective.Tag = 'perspective';
            app.perspective.Tooltip = 'Perspective Projection';
            app.perspective.Icon = 'perspective_image.png';
            app.reset = uipushtool(app.CameraToolBar);
            app.reset.Tag = 'reset';
            app.reset.Tooltip = 'Reset Camera and Scene Light';
            app.reset.Icon = 'reset_image.png';
            app.reset.Separator = 'on';
            app.stopmoving = uipushtool(app.CameraToolBar);
            app.stopmoving.Tag = 'stopmoving';
            app.stopmoving.Tooltip = 'Stop Camera/Light Motion';
            app.stopmoving.Icon = 'stopmoving_image.png';
            app.axes1 = uiaxes(app.figure);
            app.axes1.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.axes1.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.axes1.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.axes1.Tag = 'axes1';
            app.axes2 = uiaxes(app.figure);
            app.axes2.Tag = 'axes2';
            app.axes3 = uiaxes(app.figure);
            app.axes3.Tag = 'axes3';
            app.text_P = uilabel(app.figure);
            app.text_P.Tag = 'text_P';
            app.text_P.Text = 'P';
            app.text_L = uilabel(app.figure);
            app.text_L.Tag = 'text_L';
            app.text_L.Text = 'L';
            app.text_row = uilabel(app.figure);
            app.text_row.Tag = 'text_row';
            app.text_row.Text = 'Row';
            app.text_col = uilabel(app.figure);
            app.text_col.Tag = 'text_col';
            app.text_col.Text = 'Col';
            app.text_slice = uilabel(app.figure);
            app.text_slice.Tag = 'text_slice';
            app.text_slice.Text = 'Slice';
            app.edit_rr = uieditfield(app.figure, 'text');
            app.edit_rr.ValueChangedFcn = createCallbackFcn(app, @edit_rr_Callback, true);
            app.edit_rr.Tag = 'edit_rr';
            app.edit_rr.Value = '0';
            app.edit_cc = uieditfield(app.figure, 'text');
            app.edit_cc.ValueChangedFcn = createCallbackFcn(app, @edit_cc_Callback, true);
            app.edit_cc.Tag = 'edit_cc';
            app.edit_cc.Value = '0';
            app.edit_ss = uieditfield(app.figure, 'text');
            app.edit_ss.ValueChangedFcn = createCallbackFcn(app, @edit_ss_Callback, true);
            app.edit_ss.Tag = 'edit_ss';
            app.edit_ss.Value = '0';
            app.text_H = uilabel(app.figure);
            app.text_H.Tag = 'text_H';
            app.text_H.Text = 'H';
            app.text_ll = uilabel(app.figure);
            app.text_ll.Tag = 'text_ll';
            app.text_ll.Text = '0';
            app.text_pp = uilabel(app.figure);
            app.text_pp.Tag = 'text_pp';
            app.text_pp.Text = '0';
            app.text_hh = uilabel(app.figure);
            app.text_hh.Tag = 'text_hh';
            app.text_hh.Text = '0';
            app.text_voxvalstr = uilabel(app.figure);
            app.text_voxvalstr.Tag = 'text_voxvalstr';
            app.text_voxvalstr.Text = 'VoxVal:';
            app.text_voxval = uilabel(app.figure);
            app.text_voxval.Tag = 'text_voxval';
            app.text_voxval.Text = '0';
            app.decrementRowNumber_1 = uibutton(app.figure, 'push');
            app.decrementRowNumber_1.ButtonPushedFcn = createCallbackFcn(app, @decrementRowNumber_Callback, true);
            app.decrementRowNumber_1.Tag = 'decrementRowNumber_1';
            app.decrementRowNumber_1.Text = '-';
            app.incrementRowNumber_1 = uibutton(app.figure, 'push');
            app.incrementRowNumber_1.ButtonPushedFcn = createCallbackFcn(app, @incrementRowNumber_Callback, true);
            app.incrementRowNumber_1.Tag = 'incrementRowNumber_1';
            app.incrementRowNumber_1.Text = '+';
            app.decrementColumnNumber_1 = uibutton(app.figure, 'push');
            app.decrementColumnNumber_1.ButtonPushedFcn = createCallbackFcn(app, @decrementColumnNumber_Callback, true);
            app.decrementColumnNumber_1.Tag = 'decrementColumnNumber_1';
            app.decrementColumnNumber_1.Text = '-';
            app.decrementSliceNumber_1 = uibutton(app.figure, 'push');
            app.decrementSliceNumber_1.ButtonPushedFcn = createCallbackFcn(app, @decrementSliceNumber_Callback, true);
            app.decrementSliceNumber_1.Tag = 'decrementSliceNumber_1';
            app.decrementSliceNumber_1.Text = '-';
            app.incrementColumnNumber_1 = uibutton(app.figure, 'push');
            app.incrementColumnNumber_1.ButtonPushedFcn = createCallbackFcn(app, @incrementColumnNumber_Callback, true);
            app.incrementColumnNumber_1.Tag = 'incrementColumnNumber_1';
            app.incrementColumnNumber_1.Text = '+';
            app.incrementSliceNumber_1 = uibutton(app.figure, 'push');
            app.incrementSliceNumber_1.ButtonPushedFcn = createCallbackFcn(app, @incrementSliceNumber_Callback, true);
            app.incrementSliceNumber_1.Tag = 'incrementSliceNumber_1';
            app.incrementSliceNumber_1.Text = '+';
            app.cycleVolumes_1 = uibutton(app.figure, 'push');
            app.cycleVolumes_1.ButtonPushedFcn = createCallbackFcn(app, @cycleVolumes_Callback, true);
            app.cycleVolumes_1.Tag = 'cycleVolumes_1';
            app.cycleVolumes_1.Tooltip = 'Cycle through the volumes. Keyboard shortcuts: c and v';
            app.cycleVolumes_1.Text = 'Cycle';
            app.text_last = uilabel(app.figure);
            app.text_last.Tag = 'text_last';
            app.text_last.Text = 'Last Cmd:';
            app.text_command = uilabel(app.figure);
            app.text_command.Tag = 'text_command';
            app.text_command.Tooltip = 'type "help showVol" in MATLAB to list the commands';
            app.text_command.Text = 'Command:';
            app.text_last_echo = uilabel(app.figure);
            app.text_last_echo.Tag = 'text_last_echo';
            app.text_last_echo.Text = '';
            app.edit_command = uieditfield(app.figure, 'text');
            app.edit_command.ValueChangedFcn = createCallbackFcn(app, @edit_command_Callback, true);
            app.edit_command.Tag = 'edit_command';
            app.contrast_slider = uislider(app.figure);
            app.contrast_slider.Limits = [0 1];
            app.contrast_slider.MajorTicks = [];
            app.contrast_slider.ValueChangedFcn = createCallbackFcn(app, @contrast_slider_Callback, true);
            app.contrast_slider.MinorTicks = [];
            app.contrast_slider.Tag = 'contrast_slider';
            app.contrast_slider.Tooltip = 'Contrast keyboard shortcuts: q and w';
            app.contrast_reset = uibutton(app.figure, 'push');
            app.contrast_reset.ButtonPushedFcn = createCallbackFcn(app, @contrast_reset_Callback, true);
            app.contrast_reset.Tag = 'contrast_reset';
            app.contrast_reset.Tooltip = 'Reset contrast';
            app.contrast_reset.Text = 'Contrast';
            app.darken_slider = uislider(app.figure);
            app.darken_slider.Limits = [0 1];
            app.darken_slider.MajorTicks = [];
            app.darken_slider.ValueChangedFcn = createCallbackFcn(app, @darken_slider_Callback, true);
            app.darken_slider.MinorTicks = [];
            app.darken_slider.Tag = 'darken_slider';
            app.darken_slider.Tooltip = 'Darken keyboard shortcuts: a and s';
            app.zoom_slider = uislider(app.figure);
            app.zoom_slider.Limits = [0 1];
            app.zoom_slider.MajorTicks = [];
            app.zoom_slider.ValueChangedFcn = createCallbackFcn(app, @zoom_slider_Callback, true);
            app.zoom_slider.MinorTicks = [];
            app.zoom_slider.Tag = 'zoom_slider';
            app.zoom_slider.Tooltip = 'Zoom keyboard shortcuts: z and x';
            app.darken_reset = uibutton(app.figure, 'push');
            app.darken_reset.ButtonPushedFcn = createCallbackFcn(app, @darken_reset_Callback, true);
            app.darken_reset.Tag = 'darken_reset';
            app.darken_reset.Tooltip = 'Reset darken';
            app.darken_reset.Text = 'Darken';
            app.zoom_reset = uibutton(app.figure, 'push');
            app.zoom_reset.ButtonPushedFcn = createCallbackFcn(app, @zoom_reset_Callback, true);
            app.zoom_reset.Tag = 'zoom_reset';
            app.zoom_reset.Tooltip = 'Reset zoom';
            app.zoom_reset.Text = 'Zoom';
            app.storePoint = uibutton(app.figure, 'state');
            app.storePoint.ValueChangedFcn = createCallbackFcn(app, @storePoint_Callback, true);
            app.storePoint.Tag = 'storePoint';
            app.storePoint.Text = 'StoX';
            app.swapPoint = uibutton(app.figure, 'state');
            app.swapPoint.ValueChangedFcn = createCallbackFcn(app, @swapPoint_Callback, true);
            app.swapPoint.Tag = 'swapPoint';
            app.swapPoint.Text = 'SwpX';
            app.gotoPoint = uibutton(app.figure, 'state');
            app.gotoPoint.ValueChangedFcn = createCallbackFcn(app, @gotoPoint_Callback, true);
            app.gotoPoint.Tag = 'gotoPoint';
            app.gotoPoint.Text = 'ToX';
            app.text_scrvalstr = uilabel(app.figure);
            app.text_scrvalstr.Tag = 'text_scrvalstr';
            app.text_scrvalstr.Tooltip = '0 = black, 1 = white';
            app.text_scrvalstr.Text = 'ScrVal:';
            app.text_scrval = uilabel(app.figure);
            app.text_scrval.Tag = 'text_scrval';
            app.text_scrval.Text = '0';
            app.toggleLines1_1 = uibutton(app.figure, 'state');
            app.toggleLines1_1.ValueChangedFcn = createCallbackFcn(app, @toggleLines1_Callback, true);
            app.toggleLines1_1.Tag = 'toggleLines1_1';
            app.toggleLines1_1.Tooltip = 'Hide the crosshair (keyboard shortcut: t)';
            app.toggleLines1_1.Text = 'X';
            app.toggleLines2_1 = uibutton(app.figure, 'state');
            app.toggleLines2_1.ValueChangedFcn = createCallbackFcn(app, @toggleLines2_Callback, true);
            app.toggleLines2_1.Tag = 'toggleLines2_1';
            app.toggleLines2_1.Tooltip = 'Hide the crosshair';
            app.toggleLines2_1.Text = 'X';
            app.toggleLines3_1 = uibutton(app.figure, 'state');
            app.toggleLines3_1.ValueChangedFcn = createCallbackFcn(app, @toggleLines3_Callback, true);
            app.toggleLines3_1.Tag = 'toggleLines3_1';
            app.toggleLines3_1.Tooltip = 'Hide the crosshair';
            app.toggleLines3_1.Text = 'X';
            app.cycleOrientations1 = uibutton(app.figure, 'push');
            app.cycleOrientations1.ButtonPushedFcn = createCallbackFcn(app, @cycleOrientations1_Callback, true);
            app.cycleOrientations1.Tag = 'cycleOrientations1';
            app.cycleOrientations1.Tooltip = 'Make this the main view';
            app.cycleOrientations1.Text = 'O';
            app.cycleOrientations2 = uibutton(app.figure, 'push');
            app.cycleOrientations2.ButtonPushedFcn = createCallbackFcn(app, @cycleOrientations2_Callback, true);
            app.cycleOrientations2.Tag = 'cycleOrientations2';
            app.cycleOrientations2.Tooltip = 'Make this the main view';
            app.cycleOrientations2.Text = 'O';
            % Show the figure after all components are created
            app.figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = showVol_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure)

            % Execute the startup function
            runStartupFcn(app, @(app)showVolMesh_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure)
        end
    end
end